classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImageassessmentTab              matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        SelectmodelDropDown_2           matlab.ui.control.DropDown
        SelectmodelDropDown_2Label      matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        EvaluateButton                  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ObjectiveScoreLabel             matlab.ui.control.Label
        SelectthemethodDropDown         matlab.ui.control.DropDown
        SelectthemethodDropDownLabel    matlab.ui.control.Label
        NextimageButton                 matlab.ui.control.Button
        PreviousimageButton             matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        SelectanimageforassessmentLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MethodevaluationTab             matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        SelectmodelDropDown             matlab.ui.control.DropDown
        SelectmodelDropDownLabel        matlab.ui.control.Label
        NametheselectedmodelEditField   matlab.ui.control.EditField
        NametheselectedmodelEditFieldLabel  matlab.ui.control.Label
        ConfiguringtestingandtrainingsubsetsLabel  matlab.ui.control.Label
        OptionsButton                   matlab.ui.control.Button
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        SelectatestingmethodDropDown    matlab.ui.control.DropDown
        SelectatestingmethodDropDownLabel  matlab.ui.control.Label
        TestButton                      matlab.ui.control.Button
        TrainButton                     matlab.ui.control.Button
        UITable_3                       matlab.ui.control.Table
        Label_5                         matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        OpenButton_5                    matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        SelectatrainingmethodDropDown   matlab.ui.control.DropDown
        SelectatrainingmethodDropDownLabel  matlab.ui.control.Label
        OpenButton_4                    matlab.ui.control.Button
        ChoosefilewithimagesandmeanopinionscorestotrainingmethodsLabel  matlab.ui.control.Label
        MethodcomparisonTab             matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        OpenButton_ImgScore_2           matlab.ui.control.Button
        OpenButton_Corr                 matlab.ui.control.Button
        TextReultsLabel                 matlab.ui.control.Label
        TrainandtestallButton           matlab.ui.control.Button
        TabGroup2_2                     matlab.ui.container.TabGroup
        Split1Tab_2                     matlab.ui.container.Tab
        GridLayout5_2                   matlab.ui.container.GridLayout
        UITable4                        matlab.ui.control.Table
        TabGroup2                       matlab.ui.container.TabGroup
        Split1Tab                       matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        UITable3                        matlab.ui.control.Table
        SelectMethodOptionButton_2      matlab.ui.control.Button
        SelectthedatabaseLabel          matlab.ui.control.Label
        SelectMethodOptionButton        matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        SelectthemethodstocompareLabel  matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        MatchthetrainingfilestothetestfilesLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        UITable2_2                      matlab.ui.control.Table
        DeleteButton_2                  matlab.ui.control.Button
        SelectmethodandpressbuttontodeleteLabel_2  matlab.ui.control.Label
        ThelistofmethodsListBox_2       matlab.ui.control.ListBox
        ThelistofmethodsListBox_2Label  matlab.ui.control.Label
        OpenButton_3                    matlab.ui.control.Button
        AddatrainingmethodfortheimageassessmentLabel  matlab.ui.control.Label
        DeleteButton                    matlab.ui.control.Button
        SelectmethodandpressbuttontodeleteLabel  matlab.ui.control.Label
        ThelistofmethodsListBox         matlab.ui.control.ListBox
        ThelistofmethodsListBoxLabel    matlab.ui.control.Label
        OpenButton_2                    matlab.ui.control.Button
        AddnewmethodforassessingimagesLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        % Public Properties
        x % X parameter
        y % Y parameter
        listImage % List of images for processing
        imageProcessed % Processed image data
        tableValue % Values table used for calculations
        trainListImages % Training set of image list
        trainListMos % Subjective scores for the training set
        tableValueTmp % Temporary values for table updates
        locationTestImage % File path for test images
        scoreAll % Scores for all processed images
        timeTrain % Time taken for training
        timeTest % Time taken for testing
        nameTrainMethod % Name of the selected training method
        nameTestMethod % Name of the selected testing method
        flagSaver % This flag determines whether the results will be saved
        methodsTrainingFile
        trainTestFile
        methodsFile
        tableValueOptDatabase
        consNum
    end

    properties (Access = private)
        % Private Properties
        optionsMethodEvaluate % Evaluation method options
        optionsMethodSelect % Method selection options
        optionsDatabaseSelect % Database selection options
        tablesTabs % Tabs for managing training data
        tablesTabs2 % Tabs for managing testing data
        tablesMethodSelection % Selection of methods
        resultsAll % All results data
    end

    methods (Access = private)
        % Method to read images based on file type (DICOM or standard)
        function results = funcReadImage(~, location, file,x)
            if endsWith(file, '.dcm', 'IgnoreCase', true)
                results = dicomread(location);
            else
                results = imread(location);
            end

            % Convert grayscale to RGB if necessary
            if size(results, 3) == 1
                results = repmat(results, [1, 1, 3]);
            end

            if nargin > 3             % Convert to uint8 if required
                results =uint8(results);
            end

        end

        % Method to update table when removing values
        function changeRemTableValue(app, remValue, remValue2)
            if ~isempty(remValue)
                [idx, ~] = ismember(app.UITable2_2.Data.t2, remValue);
                tmp = app.UITable2_2.Data(idx, :);
                app.UITable2_2.Data(idx, :) = [];
                app.UITable2_2.Data.t2 = removecats(app.UITable2_2.Data.t2, remValue);

                % Update method list and write to file
                [idx2, ~] = ismember(app.ThelistofmethodsListBox.Items, tmp.Var1);
                if any(idx2)
                    app.ThelistofmethodsListBox.Items(idx2) = [];
                    writelines(app.ThelistofmethodsListBox.Items, app.methodsFile, "WriteMode", "overwrite", TrailingLineEndingRule = "never");
                end
            else
                [idx, ~] = ismember(app.UITable2_2.Data.Var1, remValue2);
                tmp = app.UITable2_2.Data(idx, :);
                app.UITable2_2.Data(idx, :) = [];

                % Update method list for training
                [idx2, ~] = ismember(app.ThelistofmethodsListBox_2.Items, tmp.t2);
                if any(idx2)
                    remValue = app.ThelistofmethodsListBox_2.Items(idx2);
                    app.ThelistofmethodsListBox_2.Items(idx2) = [];
                    writelines(app.ThelistofmethodsListBox_2.Items, app.methodsTrainingFile, "WriteMode", "overwrite", TrailingLineEndingRule = "never");
                    app.UITable2_2.Data.t2 = removecats(app.UITable2_2.Data.t2, remValue);
                end
            end

            % Save updated table
            writetable(app.UITable2_2.Data, app.trainTestFile);
        end

        % Method to add values to the table
        function changeAddTableValue(app, addValue, addValue2)
            if ~isempty(addValue)
                app.UITable2_2.Data.t2 = addcats(app.UITable2_2.Data.t2, addValue);
            else
                tmpTable = table(string(addValue2));
                tmpTable.t2(1, 1) = "None";
                app.UITable2_2.Data = [app.UITable2_2.Data; tmpTable];
            end

            % Save updated table
            writetable(app.UITable2_2.Data, app.trainTestFile);
        end
    end

    methods (Access = public)
        % Function to split the database into training and testing sets
        function [C, ref_ind] = funcSplitDb(~, names, numberTypeImages, percentTrain, numberPermutation, seed)
            rng(seed);
            ref_ind = repelem(1:length(names)/numberTypeImages, numberTypeImages)';
            Ref_number = max(ref_ind);
            REF = round(Ref_number * percentTrain / 100);
            C = zeros(numberPermutation, REF);

            % Generate random splits
            for j = 1:numberPermutation
                C(j, :) = randperm(Ref_number, REF);
            end
        end

        % Method for splitting data into training and testing sets
        function randomSplit(app, v1, v2, v3)
            % Enable UI elements for training and testing
            app.SelectatrainingmethodDropDown.Enable = "on";
            app.NametheselectedmodelEditField.Enable = "on";
            app.TrainButton.Enable = "on";

            % Split data using the helper function
            [C, ref_ind] = funcSplitDb(app, app.UITable.Data.Var1, v1, v2, 1, v3);
            train = ismember(ref_ind, C(1, :));
            test = ~train;

            % Update tables with training and testing data
            app.UITable_2.Data = table(app.tableValueTmp.Var1(train), app.UITable.Data.Var2(train));
            app.UITable_3.Data = table(app.tableValueTmp.Var1(test), app.UITable.Data.Var2(test));

            % Update training list and subjective scores
            app.trainListImages = app.UITable.Data.Var1(train);
            app.trainListMos = app.UITable.Data.Var2(train);

            % Manage UI element states based on data availability
            toggleUIElements(app, train, test);
        end

        % Helper function to manage UI elements
        function toggleUIElements(app, train, test)
            if ~any(train)
                app.SelectatrainingmethodDropDown.Enable = "off";
                app.TrainButton.Enable = "off";
                app.NametheselectedmodelEditField.Enable = "off";
            else
                app.NametheselectedmodelEditField.Value = app.SelectatrainingmethodDropDown.Value + "_model";
            end

            app.SelectatestingmethodDropDown.Enable = any(test);
            app.TestButton.Enable = any(test);
        end

        % Method to update method selection
        function updateMethodSelection(app, tab)
            app.tablesMethodSelection = tab;
            if size(tab,1) ~= 0 && size(tab,1) ~= 1
                app.UITable2.Data = [app.tablesMethodSelection.Var1,table2cell(table(char(app.tablesMethodSelection.t2))),table2cell(table(char(app.tablesMethodSelection.row1)))];
            elseif size(tab,1) ~= 0
                app.UITable2.Data = [app.tablesMethodSelection.Var1,((char(app.tablesMethodSelection.t2))),((char(app.tablesMethodSelection.row1)))];
            else
                app.UITable2.ColumnName = {'Methods','Train file','Train model'};
                app.UITable2.Data =[];
            end
        end

        % Method to update table tabs based on training and testing data
        function updateTableTabsValue(app, t1, t2, tableValue, consNum)
            app.tableValueOptDatabase = tableValue;
            app.consNum = consNum;
            % Clean empty columns from the tables
            [t1, t2] = cleanEmptyColumns(app, t1, t2);

            % Update properties with cleaned tables
            app.tablesTabs = t1;
            app.tablesTabs2 = t2;

            % Update Tab Group data
            updateTabGroup(app, t1, t2);
        end

        % Helper function to clean empty columns
        function [t1, t2] = cleanEmptyColumns(app, t1, t2)
            tmp = size(t1, 2);
            for i = tmp:-1:1
                if isempty(t1{i}) && isempty(t2{i})
                    t1(:, i) = [];
                    t2(:, i) = [];
                end
            end
        end

        % Helper function to update tab group based on data
        function updateTabGroup(app, t1, t2)
            numTabs = size(t1, 2);
            currentTabs = size(app.TabGroup2.Children, 1);

            if numTabs ~= 0
                % Add or remove tabs to match the data size
                if numTabs > currentTabs
                    addTabs(app, numTabs - currentTabs);
                elseif numTabs < currentTabs
                    removeTabs(app, currentTabs - numTabs);
                end

                % Update data in tabs
                for i = 1:numTabs
                    app.TabGroup2.Children(i).Children.Children.Data = t1{i};
                    app.TabGroup2_2.Children(i).Children.Children.Data = t2{i};
                end
            end
        end

        % Helper function to add tabs
        function addTabs(app, numTabsToAdd)
            for i = 1:numTabsToAdd
                createTab(app,app.TabGroup2, 'Split',1);
                createTab(app,app.TabGroup2_2, 'Split',2);
            end
        end

        % Helper function to remove tabs
        function removeTabs(app, numTabsToRemove)
            for i = 1:numTabsToRemove
                delete(app.TabGroup2.Children(end));
                delete(app.TabGroup2_2.Children(end));
            end
        end

        % Helper function to create a tab with a table
        function createTab(app,tabGroup, titlePrefix, flag)
            newTab = uitab(tabGroup, "Title", [titlePrefix, ' ', num2str(numel(tabGroup.Children) + 1)]);
            uigridlayout(newTab, [1, 1], 'ColumnWidth', "1x", 'RowHeight', "1x", 'Padding', [0, 0, 0, 0]);
            switch flag
                case 1
                    uitable(newTab.Children, 'ColumnName', {'Training image', 'Subjective Score'});
                case 2
                    uitable(newTab.Children, 'ColumnName', {'Testing image', 'Subjective Score'});
            end

        end

        function checkDirImages(app,tmp)

            % Update the Var1 column of the main table with the full paths.
            % tmp = app.tableValue.Var1;
            % app.UITable.Data = app.tableValue; % Update the UI table with the new paths.

            % Set a style for highlighting missing files in the table.
            missingStyle = uistyle("BackgroundColor", [1 0.6 0.6]);
            row = isfile(tmp); % Check which files exist in the specified location.
            row = ~row; % Identify which files are missing (i.e., not found).

            % Find the indices of missing files to apply styling.
            [rowIdx, colIdx] = find(row); % Get row and column indices of missing files.
            if ~isempty(rowIdx) && ~isempty(colIdx)
                addStyle(app.UITable, missingStyle, "cell", [rowIdx, colIdx]); % Highlight missing cells.
            end

            % Check if any files are missing.
            if sum(row) < 1
                app.Label_6.Text = ""; % Clear any previous messages.
                app.Label_6.Visible = "on"; % Make the label visible.

                % Set the background color of the main table to white.
                defaultStyle = uistyle("BackgroundColor", [1.00, 1.00, 1.00]);
                addStyle(app.UITable, defaultStyle);

                % Enable primary controls for further actions.
                app.Label_5.Enable = "on";
                app.UITable_2.Enable = "on";
                app.UITable_3.Enable = "on";

                % Check if there are any NaN values in Var2 of the table.
                if sum(isnan(app.tableValue.Var2)) > 0
                    % Prepare training and testing datasets based on NaN values in Var2.
                    app.Label_5.Enable = "off"; % Disable main label while processing.
                    app.UITable_2.Enable = "on"; % Enable secondary table for training data.
                    app.UITable_3.Enable = "on"; % Enable tertiary table for testing data.

                    % Identify training and testing samples based on NaN presence in Var2.
                    train = ~isnan(app.tableValue.Var2); % Logical array for training samples.
                    test = isnan(app.tableValue.Var2); % Logical array for testing samples.

                    % Create training table.
                    tableTmp = table(app.tableValueTmp.Var1(train), app.UITable.Data.Var2(train));
                    app.UITable_2.Data = tableTmp; % Update secondary table with training data.

                    % Create testing table.
                    tableTmp = table(app.tableValueTmp.Var1(test), app.UITable.Data.Var2(test));
                    app.UITable_3.Data = tableTmp; % Update tertiary table with testing data.

                    % Store training images and their corresponding labels.
                    app.trainListImages = app.UITable.Data.Var1(train);
                    app.trainListMos = app.UITable.Data.Var2(train);

                    % Enable or disable training controls based on available training data.
                    if isempty(app.trainListImages)
                        % No training images available; disable related controls.
                        app.SelectatrainingmethodDropDown.Enable = "off";
                        app.TrainButton.Enable = "off";
                        app.NametheselectedmodelEditField.Enable = "off";
                    else
                        % Training images are available; enable related controls.
                        app.SelectatrainingmethodDropDown.Enable = "on";
                        app.TrainButton.Enable = "on";
                        app.NametheselectedmodelEditField.Enable = "on";
                    end

                    % Enable or disable testing controls based on available testing data.
                    if isempty(app.UITable.Data.Var1(test))
                        % No testing images available; disable related controls.
                        app.SelectatestingmethodDropDown.Enable = "off";
                        app.TestButton.Enable = "off";
                    else
                        % Testing images are available; enable related controls.
                        app.SelectatestingmethodDropDown.Enable = "on";
                        app.TestButton.Enable = "on";
                    end
                else
                    % If no NaN values are found, enable all related controls.
                    app.Label_5.Enable = "on";
                    app.UITable_2.Enable = "on";
                    app.UITable_3.Enable = "on";
                    app.OptionsButton.Enable = "on"; % Enable options button for further actions.
                end
            else
                % If missing files were detected, provide user feedback.
                app.Label_6.FontColor = [1.00, 0.00, 0.00]; % Set font color to red for visibility.
                app.Label_6.Text = "The directory does not contain selected files"; % Warning message.
                app.Label_6.Visible = "on"; % Make the warning label visible.

                % Disable UI elements if files are missing.
                app.Label_5.Enable = "off";
                app.UITable_2.Enable = "off";
                app.UITable_3.Enable = "off";
                app.TrainButton.Enable = "off";
                app.TestButton.Enable = "off";
                app.SelectatrainingmethodDropDown.Enable = "off";
                app.SelectatestingmethodDropDown.Enable = "off";
                app.NametheselectedmodelEditField.Enable = "off";
                app.OpenButton_5.Enable = "on"; % Keep OpenButton enabled for re-selection.
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add necessary paths.
            addpath(genpath('Database'));
            addpath(genpath('Methods'));
            addpath(genpath('Tools'));
            addpath(genpath('Files'));

            % Suppress all warnings (consider removing this if it's not necessary)
            warning('off', 'all');

            % Load methods training if file exists.
            app.methodsTrainingFile = 'Files/methodsTraining.txt';
            if isfile(app.methodsTrainingFile)
                trainingMethods = readlines(app.methodsTrainingFile);
                app.ThelistofmethodsListBox_2.Items = trainingMethods;
                app.SelectatrainingmethodDropDown.Items = trainingMethods;
            else
                trainingMethods = [];
            end

            % Create a category list with 'None' and any methods from the training list.
            tmp = ["None"; trainingMethods];
            tmp = tmp(~cellfun('isempty', tmp)); % Remove any empty strings from tmp.

            % Load combined training and testing methods if file exists.
            app.trainTestFile = 'Files/trainTestMethodTogether.txt';
            if isfile(app.trainTestFile)
                t = readtable(app.trainTestFile);
                % Ensure t.t2 does not have missing values.
                t.t2 = fillmissing(t.t2, 'constant', {'None'});
                % Convert t.t2 to categorical with valid categories.
                t.t2 = categorical(t.t2);
                app.UITable2_2.Data = t;
            else
                % Create a default table if the file does not exist.
                t2 = repmat("none", size(app.ThelistofmethodsListBox.Items', 1), 1);
                t = table(app.ThelistofmethodsListBox.Items', t2);
                % Ensure t.t2 is a categorical variable with valid categories.
                t.t2 = categorical(t.t2, categories=tmp);
                app.UITable2_2.Data = t;
            end

            % Load methods if file exists.
            app.methodsFile = 'Files/methods.txt';
            if isfile(app.methodsFile)
                methods = readlines(app.methodsFile);
                app.ThelistofmethodsListBox.Items = methods;
                app.SelectthemethodDropDown.Items = methods;
                app.SelectatestingmethodDropDown.Items = methods;
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Open file selection dialog with specified file types.
            [file, location, ~] = uigetfile( ...
                {'*.jpeg;*.tif;*.png;*.dcm;*.bmp', 'Image Files (*.jpeg, *.tif, *.png, *.dcm, *.bmp)'}, ...
                'Select an Image File');

            % Check if the user selected a file or cancelled the dialog.
            if isequal(file, 0)
                % User pressed cancel; no further action needed.
                return;
            end

            % Enable relevant buttons once a file is selected.
            app.PreviousimageButton.Enable = 'on';
            app.NextimageButton.Enable = 'on';
            app.EvaluateButton.Enable = 'on';

            % Display the selected file's full path.
            disp(['User selected: ', fullfile(location, file)]);

            % Get a list of all supported image files in the selected directory.
            imageExtensions = {'*.dcm', '*.bmp', '*.jpeg', '*.tif', '*.png'};
            app.listImage = [];
            for ext = imageExtensions
                app.listImage = [app.listImage; dir(fullfile(location, ext{1}))];
            end

            % Find the index of the selected file in the list.
            ix = strcmp({app.listImage.name}, file);
            app.x = find(ix, 1);  % Get the index of the selected file.
            app.y = numel(ix);    % Total number of images in the directory.

            % Convert location and file into a single path string.
            imagePath = fullfile(location, file);

            % Process the selected image using a custom function.
            app.imageProcessed = funcReadImage(app, imagePath, file, 1);

            % Update the app's image display and label.
            app.Image.ImageSource = app.imageProcessed;
            app.Label_8.Text = file;
        end

        % Button pushed function: NextimageButton
        function NextimageButtonPushed(app, event)
            % Increment the image index.
            app.x = app.x + 1;

            % Wrap around to the first image if the index exceeds the total number of images.
            if app.x > app.y
                app.x = 1;
            end

            % Construct the full path of the next image.
            imagePath = fullfile(app.listImage(app.x).folder, app.listImage(app.x).name);

            % Process the selected image using a custom function.
            app.imageProcessed = funcReadImage(app, imagePath, app.listImage(app.x).name, 1);

            % Update the displayed image and labels.
            app.Image.ImageSource = app.imageProcessed;
            app.Label.Text = '';  % Clear any previous text.
            app.Label_8.Text = app.listImage(app.x).name;  % Display the name of the current image.
        end

        % Button pushed function: PreviousimageButton
        function PreviousimageButtonPushed(app, event)
            % Decrement the image index and wrap around if necessary.
            app.x = app.x - 1;

            % If the index is less than 1, wrap around to the last image.
            if app.x < 1
                app.x = app.y;
            end

            % Construct the full path of the previous image.
            imagePath = fullfile(app.listImage(app.x).folder, app.listImage(app.x).name);

            % Process the selected image using a custom function.
            app.imageProcessed = funcReadImage(app, imagePath, app.listImage(app.x).name, 1);

            % Update the displayed image and labels.
            app.Image.ImageSource = app.imageProcessed;
            app.Label.Text = '';  % Clear any previous text.
            app.Label_8.Text = app.listImage(app.x).name;  % Display the name of the current image.
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            % Display the 'waiting' message.
            app.Label.Text = 'waiting';
            pause(0.1);

            % Check if a method is selected in the dropdown.
            if isempty(app.SelectthemethodDropDown.Value)
                % If no method is selected, show an error message and exit the function.
                app.Label.Text = 'Please select a method from the dropdown.';
                return;
            end

            % Determine the function to call based on the dropdown values.
            try
                if isempty(app.SelectmodelDropDown_2.Value)
                    % Construct and evaluate the function call without a model.
                    methodCall = strcat(app.SelectthemethodDropDown.Value, '(app.imageProcessed)');
                else
                    % Construct and evaluate the function call with a model.
                    methodCall = strcat(app.SelectthemethodDropDown.Value, ...
                        '(app.imageProcessed, app.SelectmodelDropDown_2.Value)');
                end

                % Evaluate the function call and convert the result to a string.
                result = num2str(abs(eval(methodCall)));
                app.Label.Text = result;

            catch ME
                % If there is an error during the evaluation, display an error message.
                app.Label.Text = 'Error during evaluation. Please check the selected method and model.';
                % Optionally, display the error message for debugging purposes.
                disp(['Error: ', ME.message]);
            end
        end

        % Button pushed function: OpenButton_2
        function OpenButton_2Pushed(app, event)
            % Open file selection dialog for MATLAB files.
            [file, ~, ~] = uigetfile( ...
                {'*.m', 'MATLAB Files (*.m)'}, ...
                'Select a File');

            % Check if the user selected a file or cancelled the dialog.
            if ~isequal(file, 0)
                % Read the list of methods from "methods.txt".
                lines = readlines(app.methodsFile);
                selectedMethod = string(file(1:end-2));  % Remove the file extension (.m) and convert to string.

                % Check if the selected method is already in the list.
                if ~ismember(selectedMethod, lines)
                    % Append the new method to "methods.txt" if it is not already present.
                    writelines(selectedMethod, app.methodsFile, "WriteMode", "append", TrailingLineEndingRule = "never");

                    % Refresh the list of methods after adding the new entry.
                    lines = readlines(app.methodsFile);
                    app.ThelistofmethodsListBox.Items = lines;
                    app.SelectthemethodDropDown.Items = lines;
                    app.SelectatestingmethodDropDown.Items = lines;

                    % Update the table with the new method.
                    changeAddTableValue(app, [], selectedMethod);
                end
            end
        end

        % Callback function: DeleteButton, SelectmodelDropDown
        function DeleteButtonPushed(app, event)
            % Get the selected method to be deleted.
            selectedMethod = app.ThelistofmethodsListBox.Value;

            % Remove the selected method from the table.
            changeRemTableValue(app, [], selectedMethod);

            % Find the index of the selected method in the list.
            [isMember, idx] = ismember(selectedMethod, app.ThelistofmethodsListBox.Items);

            % Proceed only if the method exists in the list.
            if isMember
                % Remove the selected method from the list.
                updatedItems = app.ThelistofmethodsListBox.Items;
                updatedItems(idx) = [];

                % Update the list box and dropdown items.
                app.ThelistofmethodsListBox.Items = updatedItems;
                app.SelectthemethodDropDown.Items = updatedItems;
                app.SelectatestingmethodDropDown.Items = updatedItems;
                % Write the updated list back to "methods.txt".
                writelines(updatedItems, app.methodsFile, "WriteMode", "overwrite", TrailingLineEndingRule = "never");
            end
        end

        % Button pushed function: OpenButton_3
        function OpenButton_3Pushed(app, event)
            % Open a file selection dialog for MATLAB files.
            [file, ~, ~] = uigetfile({'*.m', 'MATLAB Files (*.m)'}, 'Select a File');

            % Check if the user selected a file or cancelled the dialog.
            if isequal(file, 0)
                % User canceled the file selection, no further action needed.
                return;
            end

            % Extract the method name by removing the file extension (.m).
            selectedMethod = string(file(1:end-2));

            % Read the current list of methods from "methodsTraining.txt".
            lines = readlines(app.methodsTrainingFile);

            % Check if the selected method is already in the list.
            if ~ismember(selectedMethod, lines)
                % Append the new method to "methodsTraining.txt" if not already present.
                writelines(selectedMethod, app.methodsTrainingFile, "WriteMode", "append", TrailingLineEndingRule = "never");

                % Refresh the list of methods after adding the new entry.
                lines = readlines(app.methodsTrainingFile);
                app.ThelistofmethodsListBox_2.Items = lines;
                app.SelectatrainingmethodDropDown.Items = lines;

                % Update the table with the new method.
                changeAddTableValue(app, selectedMethod);
            end
        end

        % Button pushed function: DeleteButton_2
        function DeleteButton_2Pushed(app, event)
            % Get the selected method to be deleted from the list box.
            selectedMethod = app.ThelistofmethodsListBox_2.Value;

            % If no method is selected, exit the function early.
            if isempty(selectedMethod)
                return;
            end

            % Remove the selected method from the table.
            changeRemTableValue(app, selectedMethod, []);

            % Find the index of the selected method in the list.
            updatedItems = app.ThelistofmethodsListBox_2.Items;
            idx = find(strcmp(updatedItems, selectedMethod), 1);

            % Proceed only if the selected method exists in the list.
            if ~isempty(idx)
                % Remove the selected method from the list.
                updatedItems(idx) = [];

                % Update the list box and dropdown menu with the new items.
                app.ThelistofmethodsListBox_2.Items = updatedItems;
                app.SelectatrainingmethodDropDown.Items = updatedItems;

                % Write the updated list back to "methodsTraining.txt".
                writelines(updatedItems, app.methodsTrainingFile, "WriteMode", "overwrite", TrailingLineEndingRule = "never");
            else
                % If the method is not found, display an error message.
            end
        end

        % Button pushed function: OpenButton_4
        function OpenButton_4Pushed(app, event)
            % Open file selection dialog for .csv and .xlsx files
            [file, location, ~] = uigetfile({'*.csv;*.xlsx', 'CSV and Excel Files (*.csv, *.xlsx)'; ...
                '*.csv', 'CSV Files (*.csv)'; ...
                '*.xlsx', 'Excel Files (*.xlsx)'}, ...
                'Select a File');

            % Check if the user selected a file or cancelled the dialog
            if isequal(file, 0)
                % User cancelled the file selection, exit the function
                return;
            end

            % Disable relevant UI elements to prevent user interaction during data loading
            app.Label_5.Enable = "off";  % Disable label indicating progress or status
            app.UITable_2.Enable = "off"; % Disable table
            app.UITable_3.Enable = "off"; % Disable table
            app.SelectatrainingmethodDropDown.Enable = "off"; % Disable method selection dropdown
            app.NametheselectedmodelEditField.Enable = "off"; % Disable model name input field

            % Read data from the selected Excel file, specifically from columns A and B
            app.tableValue = readtable(strcat(location,file), 'ReadVariableNames', false, 'Range', 'A:B');

            % Populate the primary table with the data read from the file
            app.UITable.Data = app.tableValue;

            % Enable the button for opening additional actions (OpenButton_5)
            app.OpenButton_5.Enable = "on";

            % Clear any previous data in the secondary and tertiary tables
            app.UITable_2.Data = {}; % Clear secondary table data
            app.UITable_3.Data = {}; % Clear tertiary table data

            % Create a style for the table to set the background color to white
            s = uistyle("BackgroundColor", [1 1 1]);
            addStyle(app.UITable, s); % Apply the style to the primary table

            % Hide any status or error label that may have been previously visible
            app.Label_6.Visible = "off";

            % Store a temporary copy of the loaded table data for later use
            app.tableValueTmp = app.tableValue;

            % Disable training and testing buttons until the user is ready
            app.TrainButton.Enable = "off"; % Disable training button
            app.TestButton.Enable = "off";   % Disable testing button
            app.SelectatestingmethodDropDown.Enable = "off"; % Disable testing method selection

            checkDirImages(app,app.tableValue.Var1);
            [location, nameImg, typeImg] = fileparts(app.tableValue.Var1);
            app.tableValueTmp.Var1 = strcat(nameImg,typeImg);
            app.locationTestImage = location{1};
            app.Label_7.Text = '';
        end

        % Button pushed function: OpenButton_5
        function OpenButton_5Pushed(app, event)
            % Open a directory selection dialog and set the current location.
            location = uigetdir(pwd);

            % Check if the user canceled the directory selection.
            if isequal(location, 0)
                return; % Exit the function if no directory is selected.
            end

            % Store the selected directory location for test images.
            app.locationTestImage = location;

            % Build the full path for each image file listed in Var1 of the temporary table.
            tmp = app.tableValueTmp.Var1; % Get the original file names from the temporary table.
            tmp = strcat(location, filesep, tmp); % Append the selected directory path to each filename.

            % Update the Var1 column of the main table with the full paths.
            app.tableValue.Var1 = tmp;
            app.UITable.Data = app.tableValue; % Update the UI table with the new paths.

            checkDirImages(app,app.tableValue.Var1);
            app.Label_7.Text = '';
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.TrainButton.Enable = 'off';
            % Set label properties to indicate the training process has started.
            app.Label_7.FontColor = [0, 0, 0]; % Set font color to black.
            app.Label_7.Text = 'Training in progress'; % Update label text to notify the user.

            % Pause briefly to allow the user to see the initial message.
            pause(0.1);

            % Construct the command to call the selected training method using the provided images, labels, and model name.
            w = strcat(app.SelectatrainingmethodDropDown.Value, ...
                '(app.trainListImages, app.trainListMos, app.NametheselectedmodelEditField.Value)');

            % Evaluate the constructed command to execute the training method.
            eval(w);

            % Update the label to indicate that the training process has completed successfully.
            app.Label_7.FontColor = [0.47, 0.67, 0.19]; % Set font color to a success color (greenish).
            app.Label_7.Text = 'Training completed successfully'; % Notify the user that training is finished.

            % Enable testing controls after training is complete.
            app.SelectatestingmethodDropDown.Enable = "on"; % Enable the dropdown for selecting testing methods.
            app.TestButton.Enable = "on"; % Enable the test button to allow testing of the trained model.
            app.TrainButton.Enable = 'on';
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            app.TestButton.Enable = "off";
            % Set the label to indicate that the assessment is in progress.
            app.Label_7.FontColor = [0, 0, 0]; % Set font color to black.
            app.Label_7.Text = 'Assessment in progress'; % Notify the user about the ongoing assessment.
            pause(0.1); % Brief pause to allow the user to see the update.

            % Set the column names for the results table.
            app.UITable_3.ColumnName = {'Testing set', 'Subjective Score', 'Objective Score'};

            % Prepare the locations of the test images.
            tmp = app.UITable_3.Data.Var1; % Extract the filenames from the data.
            location = strcat(app.locationTestImage, filesep, tmp); % Combine directory and filenames.

            % Initialize a score array for the objective scores.
            score = ones(size(app.UITable_3.Data.Var1, 1), 1);

            % Loop through each test image for assessment.
            for i = 1:size(app.UITable_3.Data.Var1, 1)
                % Read the image using a custom function.
                app.imageProcessed = funcReadImage(app, location{i}, app.UITable_3.Data.Var1{i});

                % Determine the method of evaluation based on the selected model.
                if isempty(app.SelectmodelDropDown.Value) % Check if no model is selected
                    if ~(app.SelectmodelDropDown.Enable == "on") % Check if the dropdown is disabled
                        % If the model dropdown is not enabled, evaluate using only the testing method.
                        w = strcat(app.SelectatestingmethodDropDown.Value, '(app.imageProcessed)');
                    else
                        % If a model should be selected but isn't, display an error message and exit.
                        app.Label_7.Text = 'Error during evaluation. Please check the selected method and model.';
                        app.TestButton.Enable = "on";
                        return; % Exit the loop and function if an error is found.
                    end
                else
                    % If a model is selected, evaluate using both the testing method and the selected model.
                    w = strcat(app.SelectatestingmethodDropDown.Value, '(app.imageProcessed, app.SelectmodelDropDown.Value)');
                end

                % Execute the evaluation command and store the score.
                score(i, :) = abs(eval(w)); % Use eval to dynamically execute the evaluation method.
            end

            % Update the third column of the results table with the scores.
            app.UITable_3.Data.Var3 = score;

            % Change label color to indicate completion of assessment.
            app.Label_7.FontColor = [0.47, 0.67, 0.19]; % Change color to indicate success.

            % Generate a filename for saving results with a timestamp.
            fileName = strcat('Results/result_', string(datetime('now', 'Format', "yyyy-MM-dd-HH-mm-ss")), '.csv');

            % Check if subjective scores are present.
            if sum(isnan(app.UITable_3.Data.Var2)) < 1
                subjective = app.UITable_3.Data.Var2; % Get the subjective scores.

                % Evaluate metrics for the subjective and objective scores.
                [spearman, kendall, pearson] = metric_evaluation(subjective, score);

                % Update label with assessment results.
                app.Label_7.Text = [
                    "Assessment of testing subset ";
                    strcat("Spearman: ", num2str(abs(spearman)), " Kendall: ", num2str(abs(kendall)), " Pearson: ", num2str(abs(pearson)));
                    strcat("Result saved in ", convertStringsToChars(fileName))
                    ];

                % Save results to CSV files.
                writecell([{"Name"}, {"Subjective Score"}, {"Objective Score"};
                    app.UITable_3.Data.Var1, num2cell(app.UITable_3.Data.Var2), num2cell(app.UITable_3.Data.Var3)], fileName);
                writematrix(["Spearman", "Kendall", "Pearson"; spearman, kendall, pearson], fileName, "WriteMode", "append");
            else
                % If no subjective scores, just notify completion.
                app.Label_7.Text = ['Assessment completed successfully', strcat("Result saved in ", fileName)];
                writecell([app.UITable_3.Data.Var1, num2cell(app.UITable_3.Data.Var2), num2cell(app.UITable_3.Data.Var3)], fileName);
            end
            app.TestButton.Enable = "on";

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Write the data from UITable2_2 to a text file using writetable.
            writetable(app.UITable2_2.Data,app.trainTestFile)
        end

        % Button pushed function: OptionsButton
        function OptionsButtonPushed(app, event)
            % Retrieve the data from the UI table (UITable).
            uiTable = app.UITable.Data;

            % Retrieve the temporary table used for comparison or processing.
            tmpTable = app.tableValueTmp;

            % Evaluate the options for methods based on the current UI table data
            % and the temporary table, storing the result in the optionsMethodEvaluate property.
            app.optionsMethodEvaluate = optionsMethodEval(app, uiTable, tmpTable);

            % Disable the Options button to prevent further clicks until processing is complete.
            app.OptionsButton.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete the options method evaluate object.
            delete(app.optionsMethodEvaluate);

            % Delete the options method select object.
            delete(app.optionsMethodSelect);

            % Delete the main application object to free resources and close the app.
            delete(app);
        end

        % Value changed function: SelectatrainingmethodDropDown
        function SelectatrainingmethodDropDownValueChanged(app, event)
            % Retrieve the currently selected value from the dropdown.
            value = app.SelectatrainingmethodDropDown.Value;

            % Update the edit field (NametheselectedmodelEditField) to reflect the
            % selected training method, appending "_model" to the selected value.
            app.NametheselectedmodelEditField.Value = app.SelectatrainingmethodDropDown.Value + "_model";
        end

        % Value changed function: SelectatestingmethodDropDown
        function SelectatestingmethodDropDownValueChanged(app, event)
            % Retrieve the currently selected value from the testing method dropdown.
            selectedTestingMethod = app.SelectatestingmethodDropDown.Value;

            % Get the data from the second table (UITable2_2) and find the corresponding entry.
            methodData = app.UITable2_2.Data;

            % Find the second column value where the first column contains the selected testing method.
            matchingEntry = methodData{find(contains(methodData{:,1}, selectedTestingMethod)), 2};

            % Check if the corresponding training method is available in the training method dropdown.
            isTrainingMethodAvailable = sum(contains(app.SelectatrainingmethodDropDown.Items, string(matchingEntry))) > 0;

            if ~isTrainingMethodAvailable
                % If not found, clear the model dropdown, disable it, and reset its value.
                app.SelectmodelDropDown.Items = {};
                app.SelectmodelDropDown.Value = {};
                app.SelectmodelDropDown.Enable = "off";
            else
                % If found, retrieve the directory containing model files based on the selected method.
                [modelPath, ~, ~] = fileparts(which(selectedTestingMethod));  % Get the path of the selected testing method.
                modelPath = fullfile(modelPath, 'models');  % Construct the path to the models directory.

                % List all .mat files in the models directory.
                modelFiles = dir(fullfile(modelPath, '*.mat'));

                % Populate the model dropdown with the names of the found model files.
                modelNames = {modelFiles.name};  % Extract the model names into a cell array.

                % Update the model dropdown items and enable it for user interaction.
                app.SelectmodelDropDown.Items = modelNames;
                app.SelectmodelDropDown.Enable = "on";
            end
        end

        % Value changed function: SelectthemethodDropDown
        function SelectthemethodDropDownValueChanged(app, event)
            % Get the selected value from the dropdown.
            selectedMethod = app.SelectthemethodDropDown.Value;

            % Retrieve the data from UITable2_2.
            methodData = app.UITable2_2.Data;

            % Find the corresponding entry in the second column where the first column contains the selected method.
            correspondingEntry = methodData{find(contains(methodData{:,1}, selectedMethod)), 2};

            % Check if the corresponding entry exists in the training method dropdown.
            isTrainingMethodAvailable = any(contains(app.SelectatrainingmethodDropDown.Items, string(correspondingEntry)));

            if ~isTrainingMethodAvailable
                % If not found, clear the model dropdown, disable it, and reset its value.
                app.SelectmodelDropDown_2.Items = {};
                app.SelectmodelDropDown_2.Value = {};
                app.SelectmodelDropDown_2.Enable = "off";
            else
                % If found, retrieve the directory containing model files based on the selected method.
                [modelPath, ~, ~] = fileparts(which(selectedMethod));  % Get the path of the selected method.
                modelDir = fullfile(modelPath, 'models');  % Construct the path to the models directory.

                % List all .mat files in the models directory.
                modelFiles = dir(fullfile(modelDir, '*.mat'));

                % Populate the model dropdown with the names of the found model files.
                modelNames = {modelFiles.name};  % Extract the model names into a cell array.

                % Update the model dropdown items and enable it for user interaction.
                app.SelectmodelDropDown_2.Items = modelNames;
                app.SelectmodelDropDown_2.Enable = "on";
            end
        end

        % Button pushed function: SelectMethodOptionButton
        function SelectMethodOptionButtonPushed(app, event)
            app.optionsMethodSelect = optionsMethodSel(app);
            app.OptionsButton.Enable = 'off';
        end

        % Button pushed function: SelectMethodOptionButton_2
        function SelectMethodOptionButton_2Pushed(app, event)
            app.optionsDatabaseSelect = optionsDatabaseSel(app,app.tablesTabs,app.tablesTabs2,event);
            app.OptionsButton.Enable = 'off';
        end

        % Button pushed function: TrainandtestallButton
        function TrainandtestallButtonPushed(app, event)
            app.TrainandtestallButton.Enable = 'off';
            app.OpenButton_Corr.Enable = "off";
            app.OpenButton_ImgScore_2.Enable = "off";
            % Check if the training mtehods table (UITable2) is empty.
            if isempty(app.UITable2.Data) || (isempty(app.UITable3.Data) && (isempty(app.UITable4.Data)))

                app.OpenButton_Corr.Enable ='off';
                app.OpenButton_ImgScore_2.Enable = 'off';
                % Display an error message if there are no training mtehod.
                app.TextReultsLabel.Text = 'Error during evaluation. Please check the selected method.';
                app.TrainandtestallButton.Enable = 'on';
                return;
            end

            for i=1:length(app.tableValueOptDatabase.Var1)
                if isfile(app.tableValueOptDatabase.Var1{i}) == 0
                    app.TextReultsLabel.Text = strcat("Error during evaluation. Image ", app.tableValueOptDatabase.Var1{i}," with given path do not exist.");
                    app.TrainandtestallButton.Enable = 'on';
                    return;
                end
            end
            % Remove trailing spaces from each cell in the training data table.
            tmp = cellfun(@(x) regexprep(x, '\s+$', ''), app.UITable2.Data, 'UniformOutput', false);
            app.scoreAll = {}; % Initialize an empty cell array to store scores.

            % Initialize variables for tracking training methods and models.
            numberOfTrainingMethods = 0;
            nameOfTrainingMethods = {};
            nameOfTrainingModelMethods = {};

            % Identify valid training methods and their corresponding models.
            for i = 1:size(tmp, 1)
                if ~ismember(strrep(tmp{i, 2}, ' ', ''), "None")
                    numberOfTrainingMethods = numberOfTrainingMethods + 1;
                    nameOfTrainingMethods{numberOfTrainingMethods} = tmp{i, 2};
                    nameOfTrainingModelMethods{numberOfTrainingMethods} = tmp{i, 3};
                end
            end

            % Filter methods that do not have a corresponding model.
            nameOfTrainingMethods_toTrain = nameOfTrainingMethods(ismember(nameOfTrainingModelMethods, "None"));
            nameOfTrainingModelMethods_toTrain = nameOfTrainingModelMethods(ismember(nameOfTrainingModelMethods, "None"));
            tmpNameOfSavedModels = {};

            % Train the models for each training method.
            for i = 1:numel(nameOfTrainingModelMethods_toTrain)
                if isempty(app.TabGroup2.Children(1).Children.Children.Data) == 1 && nameOfTrainingModelMethods_toTrain == "None"
                    app.TextReultsLabel.Text = 'Error during evaluation. Please check the selected train model.';
                    app.TrainandtestallButton.Enable = 'on';
                    return;
                end
                for j = 1:numel(app.TabGroup2.Children)
                    % Update the results label to show the current training status.
                    app.TextReultsLabel.Text = sprintf("Training %s method %d/%d and split %d/%d", ...
                        nameOfTrainingMethods_toTrain{i}, i, numel(nameOfTrainingModelMethods_toTrain), j, numel(app.TabGroup2.Children));
                    pause(0.01); % Brief pause to update the UI.

                    % Retrieve training data for the current split.
                    tmpTrainListImages = app.TabGroup2.Children(j).Children.Children.Data{:, 1};
                    tmpTrainListMos = app.TabGroup2.Children(j).Children.Children.Data{:, 2};

                    % Generate a filename for saving the model.
                    saveNameFile = sprintf('split-%d-%s-%s', j, nameOfTrainingMethods_toTrain{i}, datestr(now, 'yyyy-mm-dd-HH-MM-SS'));
                    methodCall = strcat(nameOfTrainingMethods_toTrain{i}, '(tmpTrainListImages, tmpTrainListMos, saveNameFile)');

                    % Train the model and measure the time taken.
                    tic;
                    eval(methodCall);
                    app.timeTrain{i}(j) = toc;
                    tmpNameOfSavedModels{i}{j} = saveNameFile; % Store the saved model's filename.
                end
            end

            % Test the models for each testing method.
            for j = 1:numel(app.TabGroup2_2.Children)
                for k = 1:size(app.TabGroup2_2.Children(j).Children.Children.Data, 1)
                    auxValue = 0; % Reset auxiliary value for each testing iteration.

                    for i = 1:size(tmp, 1)
                        % Update the results label to show the current testing status.
                        app.TextReultsLabel.Text = sprintf("Testing split %d/%d method %d/%d image %d/%d", ...
                            j, numel(app.TabGroup2_2.Children), i, size(tmp, 1), k, size(app.TabGroup2_2.Children(1).Children.Children.Data, 1));
                        pause(0.01); % Brief pause to update the UI.

                        % Read the test image using the custom function.
                        tmpImageProcessed = funcReadImage(app, app.TabGroup2_2.Children(j).Children.Children.Data{k, 1}{1}, app.TabGroup2_2.Children(j).Children.Children.Data{k, 1}{1});

                        % Determine the appropriate method for testing.
                        if strcmp(tmp{i, 2}, "None")
                            % Testing method without a model.
                            methodCall = strcat(tmp{i, 1}, '(tmpImageProcessed)');
                            tic;
                            app.scoreAll{i}{j}(k, :) = abs(eval(methodCall)); % Store the absolute score.
                            app.timeTest{i}(j, k) = toc; % Record the time taken.
                        elseif strcmp(tmp{i, 3}, "None")
                            % Testing method with a saved model from training.
                            auxValue = auxValue + 1; % Increment the auxiliary value.
                            modelFileName = tmpNameOfSavedModels{auxValue}{j}; % Get the saved model's filename.
                            methodCall = strcat(tmp{i, 1}, '(tmpImageProcessed, "', modelFileName, '")'); % Use the saved model.
                            tic;
                            app.scoreAll{i}{j}(k, :) = abs(eval(methodCall)); % Store the absolute score.
                            app.timeTest{i}(j, k) = toc; % Record the time taken.
                        else
                            % Testing method with a specified model.
                            modelFileName = tmp{i, 3}; % Get the specified model's filename.
                            methodCall = strcat(tmp{i, 1}, '(tmpImageProcessed, "', modelFileName, '")'); % Use the specified model.
                            tic;
                            app.scoreAll{i}{j}(k, :) = abs(eval(methodCall)); % Store the absolute score.
                            app.timeTest{i}(j, k) = toc; % Record the time taken.
                        end
                    end
                end
            end

            % Store the names of the training and testing methods for later use.
            app.nameTrainMethod = nameOfTrainingMethods_toTrain;
            app.nameTestMethod = tmp(:, 1);
            app.TextReultsLabel.Text = "Done"; % Notify user that the operation is complete.

            if isempty(app.TabGroup2_2.Children(1).Children.Children.Data) == 1
                app.OpenButton_Corr.Enable ='off';
                app.OpenButton_ImgScore_2.Enable  = 'off';
            else
                app.OpenButton_Corr.Enable ='on';
                app.OpenButton_ImgScore_2.Enable  = 'on';
            end
            app.TrainandtestallButton.Enable = 'on';
        end

        % Button pushed function: OpenButton_Corr
        function OpenButton_CorrPushed(app, event)
            app.flagSaver = 0;
            app.OpenButton_Corr.Enable = 'off';
            app.OpenButton_ImgScore_2.Enable = 'off';
            app.TrainandtestallButton.Enable = 'off';
            app.SelectMethodOptionButton_2.Enable ="off";
            app.SelectMethodOptionButton.Enable ='off';
            app.resultsAll = results(app);
        end

        % Button pushed function: OpenButton_ImgScore_2
        function OpenButton_ImgScore_2Pushed(app, event)
            if isempty(app.UITable2.Data) || (isempty(app.UITable3.Data) && (isempty(app.UITable4.Data)))
                app.flagSaver = 0;
            else
                app.flagSaver = 1;
            end
            app.OpenButton_Corr.Enable = 'off';
            app.OpenButton_ImgScore_2.Enable = 'off';
            app.TrainandtestallButton.Enable = 'off';
            app.SelectMethodOptionButton_2.Enable ="off";
            app.SelectMethodOptionButton.Enable ='off';
            app.resultsAll = results(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageassessmentTab = uitab(app.TabGroup);
            app.ImageassessmentTab.Title = 'Image assessment';
            app.GridLayout = uigridlayout(app.ImageassessmentTab);
            app.Image = uiimage(app.GridLayout);
            app.SelectanimageforassessmentLabel = uilabel(app.GridLayout);
            app.SelectanimageforassessmentLabel.Text = 'Select an image for assessment';
            app.OpenButton = uibutton(app.GridLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.PreviousimageButton = uibutton(app.GridLayout, 'push');
            app.PreviousimageButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousimageButtonPushed, true);
            app.PreviousimageButton.Enable = 'off';
            app.PreviousimageButton.Text = 'Previous image';
            app.NextimageButton = uibutton(app.GridLayout, 'push');
            app.NextimageButton.ButtonPushedFcn = createCallbackFcn(app, @NextimageButtonPushed, true);
            app.NextimageButton.Enable = 'off';
            app.NextimageButton.Text = 'Next image';
            app.SelectthemethodDropDownLabel = uilabel(app.GridLayout);
            app.SelectthemethodDropDownLabel.Text = 'Select the method ';
            app.SelectthemethodDropDown = uidropdown(app.GridLayout);
            app.SelectthemethodDropDown.Items = {''};
            app.SelectthemethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectthemethodDropDownValueChanged, true);
            app.SelectthemethodDropDown.Value = '';
            app.ObjectiveScoreLabel = uilabel(app.GridLayout);
            app.ObjectiveScoreLabel.Text = 'Objective Score';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.EvaluateButton = uibutton(app.GridLayout, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Enable = 'off';
            app.EvaluateButton.Text = 'Evaluate';
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_8.Text = '';
            app.SelectmodelDropDown_2Label = uilabel(app.GridLayout);
            app.SelectmodelDropDown_2Label.Text = ' Select model';
            app.SelectmodelDropDown_2 = uidropdown(app.GridLayout);
            app.SelectmodelDropDown_2.Items = {''};
            app.SelectmodelDropDown_2.Enable = 'off';
            app.SelectmodelDropDown_2.Value = '';
            app.MethodevaluationTab = uitab(app.TabGroup);
            app.MethodevaluationTab.Title = 'Method evaluation';
            app.GridLayout3 = uigridlayout(app.MethodevaluationTab);
            app.ChoosefilewithimagesandmeanopinionscorestotrainingmethodsLabel = uilabel(app.GridLayout3);
            app.ChoosefilewithimagesandmeanopinionscorestotrainingmethodsLabel.Text = 'Select a csv or xlsx file with image names and mean opinion scores for training';
            app.OpenButton_4 = uibutton(app.GridLayout3, 'push');
            app.OpenButton_4.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_4Pushed, true);
            app.OpenButton_4.Text = 'Open';
            app.SelectatrainingmethodDropDownLabel = uilabel(app.GridLayout3);
            app.SelectatrainingmethodDropDownLabel.Text = 'Select a training method';
            app.SelectatrainingmethodDropDown = uidropdown(app.GridLayout3);
            app.SelectatrainingmethodDropDown.Items = {''};
            app.SelectatrainingmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectatrainingmethodDropDownValueChanged, true);
            app.SelectatrainingmethodDropDown.Enable = 'off';
            app.SelectatrainingmethodDropDown.Value = '';
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Image'; 'Subjective score'};
            app.UITable.RowName = {};
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = 'Images with subjective scores';
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = 'Select corresponding directory with images';
            app.OpenButton_5 = uibutton(app.GridLayout3, 'push');
            app.OpenButton_5.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_5Pushed, true);
            app.OpenButton_5.Enable = 'off';
            app.OpenButton_5.Text = 'Open';
            app.UITable_2 = uitable(app.GridLayout3);
            app.UITable_2.ColumnName = {'Training image'; 'Subjective Score'};
            app.UITable_2.RowName = {};
            app.UITable_2.Enable = 'off';
            app.Label_5 = uilabel(app.GridLayout3);
            app.Label_5.Text = 'Preview of images from training and testing subsets with subjective scores';
            app.UITable_3 = uitable(app.GridLayout3);
            app.UITable_3.ColumnName = {'Testing image'; 'Subjective Score'};
            app.UITable_3.RowName = {};
            app.UITable_3.Enable = 'off';
            app.TrainButton = uibutton(app.GridLayout3, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Enable = 'off';
            app.TrainButton.Text = 'Train';
            app.TestButton = uibutton(app.GridLayout3, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Enable = 'off';
            app.TestButton.Text = 'Test';
            app.SelectatestingmethodDropDownLabel = uilabel(app.GridLayout3);
            app.SelectatestingmethodDropDownLabel.Text = 'Select a testing method';
            app.SelectatestingmethodDropDown = uidropdown(app.GridLayout3);
            app.SelectatestingmethodDropDown.Items = {''};
            app.SelectatestingmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectatestingmethodDropDownValueChanged, true);
            app.SelectatestingmethodDropDown.Enable = 'off';
            app.SelectatestingmethodDropDown.Value = '';
            app.Label_6 = uilabel(app.GridLayout3);
            app.Label_6.Visible = 'off';
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.GridLayout3);
            app.Label_7.Text = '';
            app.OptionsButton = uibutton(app.GridLayout3, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsButtonPushed, true);
            app.OptionsButton.Enable = 'off';
            app.OptionsButton.Text = 'Options';
            app.ConfiguringtestingandtrainingsubsetsLabel = uilabel(app.GridLayout3);
            app.ConfiguringtestingandtrainingsubsetsLabel.Text = 'Configuring testing and training subsets';
            app.NametheselectedmodelEditFieldLabel = uilabel(app.GridLayout3);
            app.NametheselectedmodelEditFieldLabel.Text = 'Name the selected model';
            app.NametheselectedmodelEditField = uieditfield(app.GridLayout3, 'text');
            app.NametheselectedmodelEditField.Enable = 'off';
            app.SelectmodelDropDownLabel = uilabel(app.GridLayout3);
            app.SelectmodelDropDownLabel.Text = ' Select model';
            app.SelectmodelDropDown = uidropdown(app.GridLayout3);
            app.SelectmodelDropDown.Items = {''};
            app.SelectmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.SelectmodelDropDown.Enable = 'off';
            app.SelectmodelDropDown.Value = '';
            app.MethodcomparisonTab = uitab(app.TabGroup);
            app.MethodcomparisonTab.Title = 'Method comparison';
            app.GridLayout4 = uigridlayout(app.MethodcomparisonTab);
            app.SelectthemethodstocompareLabel = uilabel(app.GridLayout4);
            app.SelectthemethodstocompareLabel.Text = 'Select the methods to compare';
            app.UITable2 = uitable(app.GridLayout4);
            app.UITable2.ColumnName = {'Methods'; 'Train file'; 'Train model'};
            app.UITable2.RowName = {};
            app.UITable2.SelectionType = 'row';
            app.UITable2.ColumnEditable = [false false false];
            app.SelectMethodOptionButton = uibutton(app.GridLayout4, 'push');
            app.SelectMethodOptionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMethodOptionButtonPushed, true);
            app.SelectMethodOptionButton.Text = 'Open';
            app.SelectthedatabaseLabel = uilabel(app.GridLayout4);
            app.SelectthedatabaseLabel.Text = 'Select the database';
            app.SelectMethodOptionButton_2 = uibutton(app.GridLayout4, 'push');
            app.SelectMethodOptionButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectMethodOptionButton_2Pushed, true);
            app.SelectMethodOptionButton_2.Text = 'Open';
            app.TabGroup2 = uitabgroup(app.GridLayout4);
            app.Split1Tab = uitab(app.TabGroup2);
            app.Split1Tab.Title = 'Split 1';
            app.GridLayout5 = uigridlayout(app.Split1Tab);
            app.UITable3 = uitable(app.GridLayout5);
            app.UITable3.ColumnName = {'Training image'; 'Subjective Score'};
            app.UITable3.RowName = {};
            app.TabGroup2_2 = uitabgroup(app.GridLayout4);
            app.Split1Tab_2 = uitab(app.TabGroup2_2);
            app.Split1Tab_2.Title = 'Split 1';
            app.GridLayout5_2 = uigridlayout(app.Split1Tab_2);
            app.UITable4 = uitable(app.GridLayout5_2);
            app.UITable4.ColumnName = {'Testing image'; 'Subjective Score'};
            app.UITable4.RowName = {};
            app.TrainandtestallButton = uibutton(app.GridLayout4, 'push');
            app.TrainandtestallButton.ButtonPushedFcn = createCallbackFcn(app, @TrainandtestallButtonPushed, true);
            app.TrainandtestallButton.Text = 'Train and test all';
            app.TextReultsLabel = uilabel(app.GridLayout4);
            app.TextReultsLabel.Text = '';
            app.OpenButton_Corr = uibutton(app.GridLayout4, 'push');
            app.OpenButton_Corr.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_CorrPushed, true);
            app.OpenButton_Corr.Enable = 'off';
            app.OpenButton_Corr.Text = 'Open results';
            app.OpenButton_ImgScore_2 = uibutton(app.GridLayout4, 'push');
            app.OpenButton_ImgScore_2.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_ImgScore_2Pushed, true);
            app.OpenButton_ImgScore_2.Enable = 'off';
            app.OpenButton_ImgScore_2.Text = 'Open and save results';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout2 = uigridlayout(app.SettingsTab);
            app.AddnewmethodforassessingimagesLabel = uilabel(app.GridLayout2);
            app.AddnewmethodforassessingimagesLabel.Text = 'Add new method for assessing images';
            app.OpenButton_2 = uibutton(app.GridLayout2, 'push');
            app.OpenButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_2Pushed, true);
            app.OpenButton_2.Text = 'Open';
            app.ThelistofmethodsListBoxLabel = uilabel(app.GridLayout2);
            app.ThelistofmethodsListBoxLabel.Text = 'The list of methods';
            app.ThelistofmethodsListBox = uilistbox(app.GridLayout2);
            app.ThelistofmethodsListBox.Items = {''};
            app.ThelistofmethodsListBox.Value = '';
            app.SelectmethodandpressbuttontodeleteLabel = uilabel(app.GridLayout2);
            app.SelectmethodandpressbuttontodeleteLabel.Text = 'Select method and press button to delete';
            app.DeleteButton = uibutton(app.GridLayout2, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.AddatrainingmethodfortheimageassessmentLabel = uilabel(app.GridLayout2);
            app.AddatrainingmethodfortheimageassessmentLabel.Text = 'Add a training method for the image assessment ';
            app.OpenButton_3 = uibutton(app.GridLayout2, 'push');
            app.OpenButton_3.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_3Pushed, true);
            app.OpenButton_3.Text = 'Open';
            app.ThelistofmethodsListBox_2Label = uilabel(app.GridLayout2);
            app.ThelistofmethodsListBox_2Label.Text = 'The list of methods';
            app.ThelistofmethodsListBox_2 = uilistbox(app.GridLayout2);
            app.ThelistofmethodsListBox_2.Items = {''};
            app.ThelistofmethodsListBox_2.Value = '';
            app.SelectmethodandpressbuttontodeleteLabel_2 = uilabel(app.GridLayout2);
            app.SelectmethodandpressbuttontodeleteLabel_2.Text = 'Select method and press button to delete';
            app.DeleteButton_2 = uibutton(app.GridLayout2, 'push');
            app.DeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_2Pushed, true);
            app.DeleteButton_2.Text = 'Delete';
            app.UITable2_2 = uitable(app.GridLayout2);
            app.UITable2_2.ColumnName = {'Methods testing file'; 'Methods training file'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.SelectionType = 'row';
            app.UITable2_2.ColumnEditable = [false true];
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MatchthetrainingfilestothetestfilesLabel = uilabel(app.GridLayout2);
            app.MatchthetrainingfilestothetestfilesLabel.Text = 'Match the training files to the test files';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end