classdef optionsDatabaseSel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        OkButton                    matlab.ui.control.Button
        Label_4                     matlab.ui.control.Label
        LoadButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        RandomsplitButton           matlab.ui.control.Button
        RandomseedEditField         matlab.ui.control.NumericEditField
        RandomseedEditFieldLabel    matlab.ui.control.Label
        ConsecutivenumberofimagesofthesameobjectEditField  matlab.ui.control.NumericEditField
        ConsecutivenumberofimagesofthesameobjectEditFieldLabel  matlab.ui.control.Label
        SizeofthetestingsubsetinpercentEditField  matlab.ui.control.NumericEditField
        SizeofthetestingsubsetinpercentEditFieldLabel  matlab.ui.control.Label
        SizeofthetrainingsubsetinpercentEditField  matlab.ui.control.NumericEditField
        SizeofthetrainingsubsetinpercentEditFieldLabel  matlab.ui.control.Label
        NumberofsplitsSpinner       matlab.ui.control.Spinner
        NumberofsplitsSpinnerLabel  matlab.ui.control.Label
        OpenButton_2                matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        OpenButton                  matlab.ui.control.Button
        ChoosefilewithimagesandmeanopinionscorestotrainingmethodsLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        MainApp % Description
        valueSpinner
        i
        tabs
        tabs2
        grid
        grid2
        tableValue
        location
    end

    properties (Access = public)
        TabGroup                    matlab.ui.container.TabGroup
        TabGroup2                   matlab.ui.container.TabGroup
        tablesTabs
        tablesTabs2
    end

    methods (Access = public)

        function funcSplit(app)
            if isempty(app.tableValue) == 0
               
                % C contains the indices for each split, ref_ind maps the original indices of the images.
                [C, ref_ind] = funcSplitDb(app.MainApp, ...
                    app.tableValue.Var1, ... % Original image names from the loaded table.
                    app.ConsecutivenumberofimagesofthesameobjectEditField.Value, ... % Number of consecutive images per object.
                    app.SizeofthetrainingsubsetinpercentEditField.Value, ... % Percentage size of the training subset.
                    app.NumberofsplitsSpinner.Value, ... % Number of splits to create.
                    app.RandomseedEditField.Value); % Random seed for reproducibility.

                % Loop through each split to separate training and testing datasets.
                for z = 1:app.NumberofsplitsSpinner.Value
                    % Determine which indices belong to the training set for the current split.
                    train = ismember(ref_ind, C(z, :)); % Logical array for training indices.
                    test = ~train; % Logical array for testing indices (complement of training).

                    % Update the data for the training table in the UI.
                    app.tablesTabs{z}.Data = table(app.tableValue.Var1(train), app.tableValue.Var2(train));
                    % Update the data for the testing table in the UI.
                    app.tablesTabs2{z}.Data = table(app.tableValue.Var1(test), app.tableValue.Var2(test));

                    % Check if the location for image files is not empty.
                    if isempty(app.location) == 0
                        % If a location is specified, prepend it to the training image paths.
                        tmp = app.tablesTabs{z}.Data.Var1; % Extract training image paths.
                        tmp = strcat(app.location, filesep, tmp); % Prepend location to image paths.
                        app.tablesTabs{z}.Data.Var1 = tmp; % Update the training table with new paths.

                        % Prepend location to the testing image paths similarly.
                        tmp = app.tablesTabs2{z}.Data.Var1; % Extract testing image paths.
                        tmp = strcat(app.location, filesep, tmp); % Prepend location to image paths.
                        app.tablesTabs2{z}.Data.Var1 = tmp; % Update the testing table with new paths.
                    end
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, tabTabs, tabTabs2, event)
            % Store the main application reference and hide the main UI figure.
            app.MainApp = main;
            app.MainApp.UIFigure.Visible = 'off';
            app.tableValue = app.MainApp.tableValueOptDatabase;
            % Get the number of splits from the spinner and store it.
            app.valueSpinner = app.NumberofsplitsSpinner.Value;
            app.i = app.valueSpinner; % Assign the current value of the spinner to 'i'.

            % Create the first TabGroup for displaying training and testing data.
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.Layout.Row = [5 8]; % Set the layout row for the TabGroup.
            app.TabGroup.Layout.Column = [1 4]; % Set the layout column for the TabGroup.

            % Create a second TabGroup for displaying testing data.
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.TabGroup2.Layout.Row = [5 8]; % Set the layout row for the second TabGroup.
            app.TabGroup2.Layout.Column = [5 8]; % Set the layout column for the second TabGroup.

            for j=1:app.i
                % Create a new Tab for the current split in the TabGroup.
                app.tabs{j} = uitab(app.TabGroup, "Title", ['Split ' num2str(j)]);

                % Create a GridLayout for the contents of the newly created Tab.
                app.grid{j} = uigridlayout(app.tabs{j});
                app.grid{j}.ColumnWidth = {'1x'}; % Set column width to fill available space.
                app.grid{j}.RowHeight = {'1x'};   % Set row height to fill available space.
                app.grid{j}.Padding = [0 0 0 0];   % Remove padding around the grid.

                % Create a UITable for displaying training image data and subjective scores.
                app.tablesTabs{j} = uitable(app.grid{j});
                app.tablesTabs{j}.ColumnName = {'Training image', 'Subjective score'}; % Set column names.
                app.tablesTabs{j}.Layout.Row = 1;  % Position the table in the first row.
                app.tablesTabs{j}.Layout.Column = 1; % Position the table in the first column.

                % Create a new Tab for the current split in the second TabGroup.
                app.tabs2{j} = uitab(app.TabGroup2, "Title", ['Split ' num2str(j)]);

                % Create a GridLayout for the contents of the second Tab.
                app.grid2{j} = uigridlayout(app.tabs2{j});
                app.grid2{j}.ColumnWidth = {'1x'}; % Set column width to fill available space.
                app.grid2{j}.RowHeight = {'1x'};   % Set row height to fill available space.
                app.grid2{j}.Padding = [0 0 0 0];   % Remove padding around the grid.

                % Create a UITable for displaying testing image data and subjective scores.
                app.tablesTabs2{j} = uitable(app.grid2{j});
                app.tablesTabs2{j}.ColumnName = {'Testing image', 'Subjective score'}; % Set column names.
                app.tablesTabs2{j}.Layout.Row = 1;  % Position the table in the first row.
                app.tablesTabs2{j}.Layout.Column = 1; % Position the table in the first column.
            end

            % Check if tabTabs or tabTabs2 contain any data.
            if ~isempty(tabTabs) || ~isempty(tabTabs2)
                % Update the spinner's value to match the number of tabs available.
                app.NumberofsplitsSpinner.Value = size(tabTabs, 2);
                app.ConsecutivenumberofimagesofthesameobjectEditField.Value = app.MainApp.consNum;
                NumberofsplitsSpinnerValueChanged(app, event); % Trigger the spinner's value changed event.

                % Populate the UITable for training and testing with provided data.
                for z = 1:size(tabTabs, 2)
                    app.tablesTabs{z}.Data = tabTabs{z};   % Set the data for the training UITable.
                    app.tablesTabs2{z}.Data = tabTabs2{z}; % Set the data for the testing UITable.
                end
            end

            if isempty(app.tableValue) == 0
                app.SaveButton.Enable = "on";
                app.OpenButton_2.Enable = "on";
            else
                app.SaveButton.Enable = "off";
                app.OpenButton_2.Enable = "off";
            end
        end

        % Value changed function: NumberofsplitsSpinner
        function NumberofsplitsSpinnerValueChanged(app, event)
            % Get the current value from the Number of Splits spinner.
            value = app.NumberofsplitsSpinner.Value;
            % Calculate the difference between the new value and the previous value.
            tmp = value - app.valueSpinner;
            % Update the current index for tab creation.
            app.i = app.valueSpinner + 1;

            % Loop to handle the change in the number of splits (tabs).
            for w = 1 : abs(tmp)
                if tmp > 0
                    % If the new value is greater (adding tabs):
                    % Create a new tab for the current split in the first TabGroup.
                    app.tabs{app.i} = uitab(app.TabGroup, "Title", ['Split ' num2str(app.i)]);

                    % Create a GridLayout for the contents of the new tab.
                    app.grid{app.i} = uigridlayout(app.tabs{app.i});
                    app.grid{app.i}.ColumnWidth = {'1x'}; % Set column width to fill available space.
                    app.grid{app.i}.RowHeight = {'1x'};   % Set row height to fill available space.
                    app.grid{app.i}.Padding = [0 0 0 0];   % Remove padding around the grid.

                    % Create a UITable for displaying training image data and subjective scores.
                    app.tablesTabs{app.i} = uitable(app.grid{app.i});
                    app.tablesTabs{app.i}.ColumnName = {'Training image', 'Subjective score'}; % Set column names.
                    app.tablesTabs{app.i}.Layout.Row = 1;  % Position the table in the first row.
                    app.tablesTabs{app.i}.Layout.Column = 1; % Position the table in the first column.

                    % Create a new tab for the current split in the second TabGroup.
                    app.tabs2{app.i} = uitab(app.TabGroup2, "Title", ['Split ' num2str(app.i)]);

                    % Create a GridLayout for the contents of the new tab in the second TabGroup.
                    app.grid2{app.i} = uigridlayout(app.tabs2{app.i});
                    app.grid2{app.i}.ColumnWidth = {'1x'}; % Set column width to fill available space.
                    app.grid2{app.i}.RowHeight = {'1x'};   % Set row height to fill available space.
                    app.grid2{app.i}.Padding = [0 0 0 0];   % Remove padding around the grid.

                    % Create a UITable for displaying testing image data and subjective scores.
                    app.tablesTabs2{app.i} = uitable(app.grid2{app.i});
                    app.tablesTabs2{app.i}.ColumnName = {'Testing image', 'Subjective score'}; % Set column names.
                    app.tablesTabs2{app.i}.Layout.Row = 1;  % Position the table in the first row.
                    app.tablesTabs2{app.i}.Layout.Column = 1; % Position the table in the first column.

                    % Decrement the temporary count since a new tab has been added.
                    tmp = tmp - 1;
                    app.i = app.i + 1; % Increment the index for the next tab.
                else
                    % If the new value is less (removing tabs):
                    % Delete the most recently created tab in the first TabGroup.
                    objectTab = app.tabs{app.i - 1};
                    objectTableTabs = app.tablesTabs{app.i - 1};
                    delete(objectTableTabs); % Delete the corresponding table.
                    delete(objectTab); % Delete the tab itself.

                    % Delete the most recently created tab in the second TabGroup.
                    objectTab2 = app.tabs2{app.i - 1};
                    objectTableTabs2 = app.tablesTabs2{app.i - 1};
                    delete(objectTableTabs2); % Delete the corresponding table.
                    delete(objectTab2); % Delete the tab itself.

                    % Increment the temporary count since a tab has been removed.
                    tmp = tmp + 1;
                    app.i = app.i - 1; % Decrement the index for the next tab.
                end
            end
            % Update the stored spinner value to the current value.
            app.valueSpinner = value;
            funcSplit(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.SelectMethodOptionButton.Enable = 'on';
                app.MainApp.UIFigure.Visible = 'on';
            end
            delete(app)
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Open file selection dialog for .csv and .xlsx files
            [file, ~, ~] = uigetfile({'*.csv;*.xlsx', 'CSV and Excel Files (*.csv, *.xlsx)'; ...
                '*.csv', 'CSV Files (*.csv)'; ...
                '*.xlsx', 'Excel Files (*.xlsx)'}, ...
                'Select a File');
            % Check if the user selected a file or canceled the operation.
            if isequal(file, 0)
                % User pressed Cancel; no action needed.
            else
                % % User selected a file; read the data from the selected Excel file.
                app.tableValue = readtable(file, 'ReadVariableNames', false, 'Range', 'A:B');

                if length(app.tableValue.Var1) < app.ConsecutivenumberofimagesofthesameobjectEditField.Value
                    app.ConsecutivenumberofimagesofthesameobjectEditField.Value = length(app.tableValue.Var1);
                end
                funcSplit(app)
                % Enable the buttons for further actions after loading and processing the file.
                app.SaveButton.Enable = "on"; % Enable the save button.

                if isempty(app.tableValue)==0
                    app.OpenButton_2.Enable = "on"; % Enable the second open button.
                end
            end
        end

        % Value changed function: SizeofthetrainingsubsetinpercentEditField
        function SizeofthetrainingsubsetinpercentEditFieldValueChanged(app, event)
            value = app.SizeofthetrainingsubsetinpercentEditField.Value;
            app.SizeofthetestingsubsetinpercentEditField.Value = 100-value;
        end

        % Button pushed function: RandomsplitButton
        function RandomsplitButtonPushed(app, event)
            funcSplit(app)
        end

        % Button pushed function: OpenButton_2
        function OpenButton_2Pushed(app, event)
            % Prompt the user to select a directory and store the selected path in app.location.
            app.location = uigetdir(pwd); % 'pwd' sets the current directory as the default location.

            % Check if the user canceled the directory selection.
            if isequal(app.location, 0)
            else

                % If a valid directory was selected, update the image paths in both training and testing tables.
                for z = 1:app.NumberofsplitsSpinner.Value

                    % Check if the input data is empty
                    if isempty(app.tablesTabs{z}.Data.Var1)
                        % imageData = {}; % Return an empty cell array if data is empty
                    else
                        % Extract the last part of each file path
                        imageData = app.tablesTabs{z}.Data.Var1;
                        parts = regexp(imageData, filesep, 'split');
                        app.tablesTabs{z}.Data.Var1 = cellfun(@(x) x{end}, parts, 'UniformOutput', false);
                    end

                    % Check if the input data is empty
                    if isempty(app.tablesTabs2{z}.Data.Var1)
                        % imageData = {}; % Return an empty cell array if data is empty
                    else
                        % Extract the last part of each file path
                        imageData = app.tablesTabs2{z}.Data.Var1;
                        parts = regexp(imageData, filesep, 'split');
                        app.tablesTabs2{z}.Data.Var1 = cellfun(@(x) x{end}, parts, 'UniformOutput', false);
                    end
                    % For each split, retrieve the current training image paths.
                    tmp = app.tablesTabs{z}.Data.Var1; % Extract training image paths.
                    % Prepend the selected directory path to each training image.
                    tmp = strcat(app.location, filesep, tmp); % Create full paths for training images.
                    app.tablesTabs{z}.Data.Var1 = tmp; % Update the training table with new paths.

                    % Repeat the process for testing images in the corresponding table.
                    tmp = app.tablesTabs2{z}.Data.Var1; % Extract testing image paths.
                    % Prepend the selected directory path to each testing image.
                    tmp = strcat(app.location, filesep, tmp); % Create full paths for testing images.
                    app.tablesTabs2{z}.Data.Var1 = tmp; % Update the testing table with new paths.
                end
                app.tableValue.Var1 = regexp(app.tableValue.Var1, filesep, 'split');
                app.tableValue.Var1 = cellfun(@(x) x{end}, app.tableValue.Var1, 'UniformOutput', false);
                app.tableValue.Var1 = strcat(app.location, filesep, app.tableValue.Var1);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Prompt the user to specify the filename and location to save the Excel file.
            [file, loc] = uiputfile({'*.xlsx', 'Excel Files (*.xlsx)'}, 'Save as Excel File'); % Open a dialog to choose the file location and name.

            % Check if the user canceled the save operation.
            if isequal(file, 0)
                % If the user selected 'Cancel', do nothing and exit
            else
                % If a valid filename is provided, construct the full file path.
                filename = fullfile(loc, file); % Combine the directory and filename to create a full path.

                % Save training datasets to separate sheets in the Excel file.
                for z = 1:app.NumberofsplitsSpinner.Value
                    tmp = app.tablesTabs{z}.Data; % Extract the data from the current training table.
                    name = strcat("train", num2str(z)); % Create a sheet name for training data.
                    writetable(tmp, filename, 'Sheet', name); % Write the training data to the specified sheet.
                end

                % Save testing datasets to separate sheets in the Excel file.
                for z = 1:app.NumberofsplitsSpinner.Value
                    tmp = app.tablesTabs2{z}.Data; % Extract the data from the current testing table.
                    name = strcat("test", num2str(z)); % Create a sheet name for testing data.
                    writetable(tmp, filename, 'Sheet', name); % Write the testing data to the specified sheet.
                end
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Prompt the user to select an Excel file to load.
            [file, loc, ~] = uigetfile({'*.xlsx', 'Excel Files (*.xlsx)'}, ...
                'Select an Excel File');

            % Check if the user canceled the file selection.
            if isequal(file, 0)
                % If the user selected 'Cancel', do nothing and exit
            else
                % If a valid file is selected, construct the full file path.
                filename = fullfile(loc, file); % Combine the directory and filename to create a full path.

                % Create a spreadsheet datastore object for reading the Excel file.
                ds = spreadsheetDatastore(filename);

                % Check if there are multiple sheets in the datastore.
                if size(sheetnames(ds, 1), 1) > 1
                    % If there are multiple sheets, calculate the number of training splits.
                    num = size(sheetnames(ds, 1), 1) / 2; % Assume half of the sheets are for training and half for testing.
                    app.NumberofsplitsSpinner.Value = num; % Update the spinner value to reflect the number of splits.

                    % Call the value changed function to handle any updates needed when the spinner value changes.
                    NumberofsplitsSpinnerValueChanged(app, event)

                    % Loop through each training sheet and load the data into the corresponding tables.
                    for z = 1:num
                        ds.Sheets = z; % Set the current sheet to read.
                        app.tablesTabs{z}.Data = read(ds); % Read the data from the current sheet and store it in the training table.
                    end

                    % Temporary variable to index testing sheets.
                    tmp = 1;
                    % Loop through each testing sheet and load the data into the corresponding tables.
                    for z = num + 1:size(sheetnames(ds, 1), 1)
                        ds.Sheets = z; % Set the current sheet to read.
                        app.tablesTabs2{tmp}.Data = read(ds); % Read the data from the current sheet and store it in the testing table.
                        tmp = tmp + 1; % Increment the temporary variable for the next testing table.
                    end

                else
                    % If there is only one sheet, read it directly into the first training table.
                    ds.Sheets = 1; % Set to read the first sheet.
                    app.tablesTabs{1}.Data = read(ds); % Read the data and store it in the first training table.
                end
            end
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % Loop through each tab in the main application to retrieve data.
            for z = 1:size(app.TabGroup.Children, 1) % Iterate over each child tab in TabGroup.
                % Store data from the first tab (Data) into a temporary variable.
                tmpTableValue{z} = app.TabGroup.Children(z).Children.Children.Data;
                % Store data from the second tab (Data) into another temporary variable.
                tmpTableValue2{z} = app.TabGroup2.Children(z).Children.Children.Data;
            end

            % Call a function to update the table tabs in the main application with the collected data.
            updateTableTabsValue(app.MainApp, tmpTableValue, tmpTableValue2, app.tableValue, app.ConsecutivenumberofimagesofthesameobjectEditField.Value);

            % Check if the main application object is still valid.
            if isvalid(app.MainApp)
                % Enable the method selection option button in the main application.
                app.MainApp.SelectMethodOptionButton.Enable = 'on';
                % Make the main application window visible.
                app.MainApp.UIFigure.Visible = 'on';
            end

            % Close the current app window by deleting its object.
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ChoosefilewithimagesandmeanopinionscorestotrainingmethodsLabel = uilabel(app.GridLayout);
            app.ChoosefilewithimagesandmeanopinionscorestotrainingmethodsLabel.Text = 'Select csv or xlsx file with image names and mean opinion scores for training';
            app.OpenButton = uibutton(app.GridLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Select corresponding directory with images';
            app.OpenButton_2 = uibutton(app.GridLayout, 'push');
            app.OpenButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_2Pushed, true);
            app.OpenButton_2.Enable = 'off';
            app.OpenButton_2.Text = 'Open';
            app.NumberofsplitsSpinnerLabel = uilabel(app.GridLayout);
            app.NumberofsplitsSpinnerLabel.Text = 'Number of splits';
            app.NumberofsplitsSpinner = uispinner(app.GridLayout);
            app.NumberofsplitsSpinner.Limits = [1 Inf];
            app.NumberofsplitsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofsplitsSpinnerValueChanged, true);
            app.NumberofsplitsSpinner.Value = 10;
            app.SizeofthetrainingsubsetinpercentEditFieldLabel = uilabel(app.GridLayout);
            app.SizeofthetrainingsubsetinpercentEditFieldLabel.Text = 'Size of the training subset (in percent)';
            app.SizeofthetrainingsubsetinpercentEditField = uieditfield(app.GridLayout, 'numeric');
            app.SizeofthetrainingsubsetinpercentEditField.Limits = [0 100];
            app.SizeofthetrainingsubsetinpercentEditField.ValueChangedFcn = createCallbackFcn(app, @SizeofthetrainingsubsetinpercentEditFieldValueChanged, true);
            app.SizeofthetrainingsubsetinpercentEditField.Value = 80;
            app.SizeofthetestingsubsetinpercentEditFieldLabel = uilabel(app.GridLayout);
            app.SizeofthetestingsubsetinpercentEditFieldLabel.Text = 'Size of the testing subset (in percent)';
            app.SizeofthetestingsubsetinpercentEditField = uieditfield(app.GridLayout, 'numeric');
            app.SizeofthetestingsubsetinpercentEditField.Limits = [0 100];
            app.SizeofthetestingsubsetinpercentEditField.Editable = 'off';
            app.SizeofthetestingsubsetinpercentEditField.Value = 20;
            app.ConsecutivenumberofimagesofthesameobjectEditFieldLabel = uilabel(app.GridLayout);
            app.ConsecutivenumberofimagesofthesameobjectEditFieldLabel.Text = 'Consecutive number of images of the same object (don''t mix  in training/testing subset)';
            app.ConsecutivenumberofimagesofthesameobjectEditField = uieditfield(app.GridLayout, 'numeric');
            app.ConsecutivenumberofimagesofthesameobjectEditField.Limits = [1 Inf];
            app.ConsecutivenumberofimagesofthesameobjectEditField.Value = 2;
            app.RandomseedEditFieldLabel = uilabel(app.GridLayout);
            app.RandomseedEditFieldLabel.Text = 'Random seed';
            app.RandomseedEditField = uieditfield(app.GridLayout, 'numeric');
            app.RandomseedEditField.Limits = [0 4294967295];
            app.RandomsplitButton = uibutton(app.GridLayout, 'push');
            app.RandomsplitButton.ButtonPushedFcn = createCallbackFcn(app, @RandomsplitButtonPushed, true);
            app.RandomsplitButton.Text = 'Random split';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = 'Divide the database into training and testing images';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = 'Save split or load';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = 'Accept splits';
            app.OkButton = uibutton(app.GridLayout, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optionsDatabaseSel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end