classdef optionsMethodEval < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        Label_2                   matlab.ui.control.Label
        RandomsplitButton         matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        RandomseedEditField       matlab.ui.control.NumericEditField
        RandomseedEditFieldLabel  matlab.ui.control.Label
        SizeofthetestingsubsetinpercentEditField  matlab.ui.control.NumericEditField
        SizeofthetestingsubsetinpercentEditFieldLabel  matlab.ui.control.Label
        ConsecutivenumberofimagesofthesameobjectEditField  matlab.ui.control.NumericEditField
        ConsecutivenumberofimagesofthesameobjectEditFieldLabel  matlab.ui.control.Label
        SizeofthetrainingsubsetinpercentEditField  matlab.ui.control.NumericEditField
        SizeofthetrainingsubsetinpercentEditFieldLabel  matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
    end

    properties (Access = private)
        MainApp % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, uiTable, tmpTable)
            app.UITable.Data = uiTable;
            app.MainApp = main;
            app.SizeofthetestingsubsetinpercentEditField.Value = 100-app.SizeofthetrainingsubsetinpercentEditField.Value;
            if size(app.UITable.Data.Var1,1) < app.ConsecutivenumberofimagesofthesameobjectEditField.Value
                app.ConsecutivenumberofimagesofthesameobjectEditField.Value = size(app.UITable.Data.Var1,1);
                app.ConsecutivenumberofimagesofthesameobjectEditField.Limits = [1 app.ConsecutivenumberofimagesofthesameobjectEditField.Value];
            else
                app.ConsecutivenumberofimagesofthesameobjectEditField.Limits = [1, size(app.UITable.Data.Var1,1)];
            end
        end

        % Button pushed function: RandomsplitButton
        function RandomsplitButtonPushed(app, event)
            randomSplit(app.MainApp,app.ConsecutivenumberofimagesofthesameobjectEditField.Value, ...
                app.SizeofthetrainingsubsetinpercentEditField.Value,app.RandomseedEditField.Value)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.OptionsButton.Enable = 'on';
            end
            delete(app)
        end

        % Value changed function: SizeofthetrainingsubsetinpercentEditField
        function SizeofthetrainingsubsetinpercentEditFieldValueChanged(app, event)
            value = app.SizeofthetrainingsubsetinpercentEditField.Value;
            app.SizeofthetestingsubsetinpercentEditField.Value = 100-value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Image'; 'Subjective score'};
            app.UITable.RowName = {};
            app.SizeofthetrainingsubsetinpercentEditFieldLabel = uilabel(app.GridLayout);
            app.SizeofthetrainingsubsetinpercentEditFieldLabel.Text = 'Size of the training subset (in percent)';
            app.SizeofthetrainingsubsetinpercentEditField = uieditfield(app.GridLayout, 'numeric');
            app.SizeofthetrainingsubsetinpercentEditField.Limits = [0 100];
            app.SizeofthetrainingsubsetinpercentEditField.ValueChangedFcn = createCallbackFcn(app, @SizeofthetrainingsubsetinpercentEditFieldValueChanged, true);
            app.SizeofthetrainingsubsetinpercentEditField.Value = 80;
            app.ConsecutivenumberofimagesofthesameobjectEditFieldLabel = uilabel(app.GridLayout);
            app.ConsecutivenumberofimagesofthesameobjectEditFieldLabel.Text = 'Consecutive number of images of the same object (don''t mix  in training/testing subset)';
            app.ConsecutivenumberofimagesofthesameobjectEditField = uieditfield(app.GridLayout, 'numeric');
            app.ConsecutivenumberofimagesofthesameobjectEditField.Limits = [1 Inf];
            app.ConsecutivenumberofimagesofthesameobjectEditField.Value = 2;
            app.SizeofthetestingsubsetinpercentEditFieldLabel = uilabel(app.GridLayout);
            app.SizeofthetestingsubsetinpercentEditFieldLabel.Text = 'Size of the testing subset (in percent)';
            app.SizeofthetestingsubsetinpercentEditField = uieditfield(app.GridLayout, 'numeric');
            app.SizeofthetestingsubsetinpercentEditField.Limits = [0 100];
            app.SizeofthetestingsubsetinpercentEditField.Editable = 'off';
            app.RandomseedEditFieldLabel = uilabel(app.GridLayout);
            app.RandomseedEditFieldLabel.Text = 'Random seed';
            app.RandomseedEditField = uieditfield(app.GridLayout, 'numeric');
            app.RandomseedEditField.Limits = [0 4294967295];
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Divide the database into training and testing images';
            app.RandomsplitButton = uibutton(app.GridLayout, 'push');
            app.RandomsplitButton.ButtonPushedFcn = createCallbackFcn(app, @RandomsplitButtonPushed, true);
            app.RandomsplitButton.Text = 'Random split';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = 'Images with subjective scores';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optionsMethodEval(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end