classdef optionsMethodSel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        OkButton    matlab.ui.control.Button
        UITable     matlab.ui.control.Table
    end


    properties (Access = private)
        MainApp % Description
        tmp2
        flag
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            % Initialize the main application and hide its UI figure.
            app.MainApp = main;
            app.MainApp.UIFigure.Visible = 'off';
            app.flag =0;

            % Check if the app.MainApp.methodsTrainingFile file exists.
            if isfile(app.MainApp.methodsTrainingFile)
                % Read the lines from app.MainApp.methodsTrainingFile.
                lines = readlines(app.MainApp.methodsTrainingFile);

                % Initialize variables.
                tmp = ["None"; lines];
                tmpCategorical = categorical(tmp);

                % Check if "trainTestMethodTogether.txt" exists.
                if isfile(app.MainApp.trainTestFile)
                    % Read the table and set categories.
                    t = readtable(app.MainApp.trainTestFile);
                    t.t2 = categorical(t.t2, tmp);
                else
                    % Create a new table with "none" values for t2.
                    t2 = repmat("none", size(app.ThelistofmethodsListBox.Items', 1), 1);
                    t = table(app.ThelistofmethodsListBox.Items', t2, 'VariableNames', {'Methods', 't2'});
                    t.t2 = categorical(t.t2, tmp);
                end

                % Set the initial table data.
                app.UITable.Data = t;

                % Read the methods from app.MainApp.methodsFile and initialize Var2 column.
                methodsLines = readlines(app.MainApp.methodsFile);
                Var2 = table(false(size(methodsLines)), 'VariableNames', {'Var2'});
                app.UITable.Data = [app.UITable.Data, Var2];

                % Initialize row1 to store categories for models.
                row1 = categorical();

                % Iterate over each row in t2 to assign the corresponding categories.
                for i = 1:height(app.UITable.Data)
                    currentMethod = app.UITable.Data.t2(i);
                    if currentMethod == 'None'
                        row1 = [row1; categorical({'None'}, {'None'})];
                    else
                        % Get the model directory path.
                        modelPath = fullfile(fileparts(which(char(currentMethod))), 'models');
                        modelFiles = dir(fullfile(modelPath, '*.mat'));

                        if numel(modelFiles) > 1
                            modelNames = {modelFiles.name}';
                        else
                            modelNames = {'None'};
                        end

                        % Append the model names as categories.
                        row1 = [row1; categorical({'None'}, modelNames)];
                    end
                end

                % Replace non-matching categories with "None".
                noneCategory = categorical("None");
                row1(~ismember(row1, noneCategory)) = noneCategory;

                % Add the row1 column to the table.
                app.UITable.Data = [app.UITable.Data, table(row1)];

                % Update app.tmp2 to indicate rows with 'None' in t2.
                app.tmp2 = ismember(app.UITable.Data.t2, 'None');
            else
                app.flag = 1;
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Check if the MainApp is still valid (not deleted or closed).
            if isvalid(app.MainApp)
                % Enable the 'SelectMethodOptionButton' in the MainApp.
                app.MainApp.SelectMethodOptionButton.Enable = 'on';

                % Make the MainApp's UIFigure visible again.
                app.MainApp.UIFigure.Visible = 'on';
            end

            % Delete the current app instance, closing the UIFigure.
            delete(app)
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % Check if the flag is set to 0.
            if app.flag == 0
                % Update method selection in the MainApp with filtered data (rows where Var2 is true).
                updateMethodSelection(app.MainApp, app.UITable.Data(app.UITable.Data.Var2, :))
            else
                % If flag is not 0, update method selection with the entire table data.
                updateMethodSelection(app.MainApp, app.UITable.Data)
            end

            % Check if the MainApp is still valid (not deleted or closed).
            if isvalid(app.MainApp)
                % Enable the 'SelectMethodOptionButton' in the MainApp.
                app.MainApp.SelectMethodOptionButton.Enable = 'on';

                % Make the MainApp's UIFigure visible again.
                app.MainApp.UIFigure.Visible = 'on';
            end

            % Delete the current app instance, effectively closing it.
            delete(app)
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            % Get the current selection from the UITable.
            selection = app.UITable.Selection;

            % Check if the selection is empty (no row is selected).
            if isempty(selection)
                % If no selection is made, do nothing.
            else
                % If a selection is made, check the value of app.tmp2 at the selected row.
                % app.tmp2 indicates whether the selected method is 'None'.
                if app.tmp2(selection(1)) == 1
                    % If the selected row corresponds to 'None', make only the third column editable.
                    app.UITable.ColumnEditable = logical([0, 0, 1, 0]);
                else
                    % If the selected row does not correspond to 'None', make the third and fourth columns editable.
                    app.UITable.ColumnEditable = logical([0, 0, 1, 1]);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Methods'; 'Train file'; 'Select methods'; 'Train model'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true true];
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.OkButton = uibutton(app.GridLayout, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optionsMethodSel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end