classdef results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CorrelationsTab                 matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ImageScoresTab                  matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        WilcoxonTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        BoxplotsTab                     matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        TimeTab                         matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        MeantesttimeforanimageLabel     matlab.ui.control.Label
        MeantrainingtimeforasplitLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        MainApp % Main application object for accessing shared data and functions
        TabGroupCorr % Group for holding correlation result tabs
        tabsCorr % Array to store individual correlation result tabs
        gridCorr % Array to hold grid layouts for each correlation tabSpearman
        tablesTabsCorr % Array to store UITable objects for displaying correlation metrics

        TabGroupImScore % Group for holding image score tabs
        tabsImScore % Array to store individual image score tabs
        gridImScore % Array to hold grid layouts for each image score tab
        tablesTabsImScore % Array to store UITable objects for displaying image scores

        TabGroupWilcoxon % Group for holding Wilcoxon test result tabs
        tabsWilcoxon % Array to store individual Wilcoxon test tabs
        gridWilcoxon % Array to hold grid layouts for each Wilcoxon test tab
        tablesTabsWilcoxon % Array to store UITable objects for displaying Wilcoxon test results

        TabBoxplotsTab % Group for holding boxplot tabs
        tabsBoxplots % Array to store individual boxplot tabs
        gridBoxplots % Array to hold grid layouts for each boxplot tab
        tabsUIAxes % Array to store UI axes for rendering boxplots

        tablesComplexityTab % Array to store UITable objects for displaying complexity metrics
        tmpOutScore
    end

    methods (Access = private)

        function results = wilcoxonFun(~, data, corrType, name)
            % Define a function named wilcoxonFun that performs pairwise Wilcoxon rank-sum tests.
            % Inputs:
            % - data: A cell array containing the datasets to compare
            % - corrType: The column index to use for comparison in the data
            % - name: A cell array of names corresponding to each dataset
            % Output:
            % - results: A cell array that stores the results of the Wilcoxon tests

            % Get the number of datasets to compare
            x = size(data, 1);

            % Initialize an array to store the test results (1 for positive, -1 for negative, 0 for no difference)
            score_array = zeros(x, x);

            % Populate the first column of the results with dataset names
            for i = 1:x
                results{1 + i, 1} = name{i};
            end

            % Populate the first row of the results with dataset names
            for i = 1:x
                results{1, i + 1} = name{i};
            end

            % Perform pairwise Wilcoxon rank-sum tests for all dataset pairs
            for i = 1:x
                for ii = 1:x
                    % Extract data for the ith and ii-th datasets
                    test  = data{i};
                    test2 = data{ii};

                    % Perform the Wilcoxon rank-sum test using the specified column (corrType)
                    [~, h] = ranksum(test(:, corrType), test2(:, corrType));

                    % If a significant difference (h == 1) is found, check the direction of the difference
                    if h == 1
                        % Set h to 1 if the median of test is greater, otherwise set h to -1
                        if median(test(:, corrType)) > median(test2(:, corrType))
                            h = 1; % Indicates that test is greater than test2
                        else
                            h = -1; % Indicates that test is less than test2
                        end
                    end

                    % Store the test result in the score array (1, -1, or 0)
                    score_array(i, ii) = double(h);
                end
            end

            % Transfer the score_array values into the results table, starting from the second row and column
            for i = 2:x + 1
                for ii = 2:x + 1
                    results{i, ii} = score_array(i - 1, ii - 1);
                end
            end
        end

        function createAllResultTab(app, data)
            % Create tabs and populate them with results, images, and statistics.

            % Extract the data from the UITable
            tmp = strrep(data.UITable2.Data, ' ', '');
            numSplits = size(data.TabGroup2_2.Children, 1);

            % Compute the subjective and objective scores for each split and method
            [tmpScore, tmpScore1] = computeScores(app, data, tmp, numSplits);

            % Replace NaN with zero in scores
            tmpScore = cellfun(@(x) replaceNaNWithZero(app, x), tmpScore, 'UniformOutput', false);

            % Create tabs for displaying correlation results
            createCorrelationTabs(app, tmp, tmpScore, tmpScore1, numSplits);

            % Create tabs for displaying image scores
            createImageScoreTabs(app, data, tmp, numSplits);

            % Create Wilcoxon test tabs
            createWilcoxonTabs(app, tmpScore, tmp);

            % Create Boxplot tabs for correlation metrics
            createBoxplotTabs(app, tmpScore, tmp);

            % Create complexity tables for training and testing time complexity
            createComplexityTables(app, tmpScore);

            app.tmpOutScore = tmpScore;
        end

        function [tmpScore, tmpScore1] = computeScores(~, data, tmp, numSplits)
            % Compute the Spearman, Kendall, and Pearson scores for each method and split
            tmpScore = cell(size(tmp, 1), 1);
            for j = 1:numSplits
                for i = 1:size(tmp, 1)
                    % Extract subjective scores for the current split
                    subjectiveScores = data.TabGroup2_2.Children(j).Children.Children.Data{:, 2};
                    % Extract objective scores for the current method and split
                    objectiveScores = data.scoreAll{i}{j};
                    % Compute Spearman, Kendall, and Pearson correlation coefficients
                    [spearman, kendall, pearson] = metric_evaluation(subjectiveScores, objectiveScores);
                    % Store the absolute values of the scores in tmpScore for the current method and split
                    tmpScore{i}(j, :) = [abs(spearman), abs(kendall), abs(pearson)];
                end
            end
            % Compute the median of the scores across splits for each method
            tmpScore1 = cellfun(@(x) median(x, 1), tmpScore, 'UniformOutput', false);
        end

        function scoreMatrix = replaceNaNWithZero(~, scoreMatrix)
            % Replace NaN values with zero in the provided matrix
            scoreMatrix(isnan(scoreMatrix)) = 0;
        end

        function createCorrelationTabs(app, tmp, tmpScore, tmpScore1, numSplits)
            % Create tabs for displaying correlation results and populate
            % the data only if tmpScore is not empty
            if ~isempty(tmpScore)
                for j = 1:numSplits
                    app.tabsCorr{j + 1} = uitab(app.TabGroupCorr, "Title", ['Split ' num2str(j)]);
                    app.gridCorr{j + 1} = createGridLayout(app, app.tabsCorr{j + 1});
                    app.tablesTabsCorr{j + 1} = createUITable(app, app.gridCorr{j + 1}, {'Method', 'Spearman', 'Kendall', 'Pearson'});

                    % Populate the data for each method
                    for i = 1:size(tmp, 1)
                        newRow = [tmp(i, 1), num2cell(tmpScore{i}(j, :))];
                        app.tablesTabsCorr{j + 1}.Data = [app.tablesTabsCorr{j + 1}.Data; newRow];
                    end
                end
                % Populate the data for each method
                for i = 1:size(tmp, 1)
                    newRow = [tmp(i, 1), num2cell(tmpScore1{i})];
                    app.tablesTabsCorr{1}.Data = [app.tablesTabsCorr{1}.Data; newRow];
                end
            else
                for i = 1:numSplits
                    app.tablesTabsCorr{i}.Data = {'No data available for Correlations'};
                end
            end
        end


        function createImageScoreTabs(app, data, tmp, numSplits)
            % Create tabs for displaying image scores
            app.TabGroupImScore = uitabgroup(app.GridLayout5);
            app.TabGroupImScore.Layout.Row = [1 17];
            app.TabGroupImScore.Layout.Column = [1 8];

            % Adjust column names based on whether tmp is empty
            if isempty(tmp)
                columnNames = {'Image name', 'Subjective Score'};
            else
                columnNames = ['Image name', 'Subjective Score', tmp(:, 1)'];
            end

            for i = 1:numSplits
                % Create each split tab and the corresponding grid layout and table
                app.tabsImScore{i} = uitab(app.TabGroupImScore, "Title", ['Split ' num2str(i)]);
                app.gridImScore{i} = createGridLayout(app, app.tabsImScore{i});
                app.tablesTabsImScore{i} = createUITable(app, app.gridImScore{i}, columnNames);

                % Extract image data and subjective scores
                imageData = extractImageData(app, data.TabGroup2_2.Children(i).Children.Children.Data);

                % Check if subjectiveScores is empty and adjust if needed
                if isempty(data.TabGroup2_2.Children(i).Children.Children.Data)
                    subjectiveScores = nan(size(imageData)); % Use NaN to indicate missing scores
                else
                    subjectiveScores = data.TabGroup2_2.Children(i).Children.Children.Data{:, 2};
                end

                % Handle objective scores if tmp is not empty
                if ~isempty(tmp)
                    objectiveScores = cellfun(@(x) num2cell(x{i}), data.scoreAll(1:end), 'UniformOutput', false);
                    app.tablesTabsImScore{i}.Data = [imageData, num2cell(subjectiveScores), objectiveScores{:}];
                else
                    % If tmp is empty, only include image names and subjective scores
                    app.tablesTabsImScore{i}.Data = {'No data available for image scores'};
                end
            end
        end

        function createWilcoxonTabs(app, tmpScore, tmp)
            % Create tabs for Wilcoxon test results
            app.TabGroupWilcoxon = uitabgroup(app.GridLayout2);
            app.TabGroupWilcoxon.Layout.Row = [1 17];
            app.TabGroupWilcoxon.Layout.Column = [1 8];
            titles = {'Spearman', 'Kendall', 'Pearson'};

            for i = 1:numel(titles)
                % Create the tab for each title
                app.tabsWilcoxon{i} = uitab(app.TabGroupWilcoxon, "Title", titles{i});
                app.gridWilcoxon{i} = createGridLayout(app, app.tabsWilcoxon{i});
                app.tablesTabsWilcoxon{i} = createUITable(app, app.gridWilcoxon{i});

                % Insert data into Wilcoxon table only if tmpScore is not empty
                if ~isempty(tmpScore)
                    results = wilcoxonFun(app, tmpScore, i, tmp);
                    app.tablesTabsWilcoxon{i}.Data = results;
                else
                    % If tmpScore is empty, you can provide a message or handle it differently
                    app.tablesTabsWilcoxon{i}.Data = {'No data available for Wilcoxon test'};
                end

                % Apply cell styling based on Wilcoxon test results
                applyCellStyling(app, app.tablesTabsWilcoxon{i}, app.tablesTabsWilcoxon{i}.Data);
            end
        end

        function createBoxplotTabs(app, tmpScore, tmp)
            % Create boxplot tabs for each correlation metric
            titles = {'Spearman', 'Kendall', 'Pearson'};

            for j = 1:3
                pause(0.1)
                app.tabsBoxplots{j} = uitab(app.TabBoxplotsTab, "Title", titles{j});
                app.gridBoxplots{j} = createGridLayout(app, app.tabsBoxplots{j});
                app.tabsUIAxes{j} = uiaxes(app.gridBoxplots{j});

                % Check if tmp is empty
                if isempty(tmp) || size(tmpScore{1}, 1) == 1
                    % Display a message when there is no data
                    text(app.tabsUIAxes{j}, 0.5, 0.5, 'No data available for plotting', ...
                        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
                        'FontSize', 12, 'Color', 'red');
                    app.tabsUIAxes{j}.XTick = []; % Remove X-ticks for clarity
                    app.tabsUIAxes{j}.YTick = []; % Remove Y-ticks for clarity
                else
                    % Prepare data for boxplot when tmp is not empty
                    for i = 1:size(tmpScore, 1)
                        % Using the original method to populate tmpTable
                        tmpTable(:, i) = tmpScore{i}(:, j);
                    end

                    % Create the box plot and get the handle
                    h = boxplot(app.tabsUIAxes{j}, tmpTable, "Labels", tmp(:, 1));
                    % Set the Y-axis limits to be between 0 and 1
                    ylim(app.tabsUIAxes{j}, [0 1]);
                    % Customize the boxplot appearance
                    customizeBoxplotAppearance(app, h);
                end
            end
        end

        function createComplexityTables(app, tmpScore)
            % Create tables for displaying training and testing time complexity
            app.tablesComplexityTab{1} = createUITable(app, app.GridLayout4, {'Time [s]'}, app.MainApp.nameTrainMethod);
            app.tablesComplexityTab{2} = createUITable(app, app.GridLayout4, {'Time [s]'}, app.MainApp.nameTestMethod);
            app.tablesComplexityTab{1}.Layout.Row = [2 8];
            app.tablesComplexityTab{1}.Layout.Column = [3 6];
            app.tablesComplexityTab{2}.Layout.Row = [10 17];
            app.tablesComplexityTab{2}.Layout.Column = [3 6];
            if ~isempty(tmpScore)
                if ~isempty(app.MainApp.timeTrain)
                    app.tablesComplexityTab{1}.Data = (cellfun(@mean, app.MainApp.timeTrain))';
                end
                app.tablesComplexityTab{2}.Data = (cellfun(@(x) mean(x, 'all'), app.MainApp.timeTest))';
            else
                app.tablesComplexityTab{1}.Data = {'No data available for computional complexity'};
                app.tablesComplexityTab{2}.Data = {'No data available for computional complexity'};
            end
        end

        % Helper functions for UI element creation
        function gridLayout = createGridLayout(~, parent)
            gridLayout = uigridlayout(parent);
            gridLayout.ColumnWidth = {'1x'};
            gridLayout.RowHeight = {'1x'};
            gridLayout.Padding = [0 0 0 0];
        end

        function table = createUITable(~, parent, columnNames, rowNames)
            % Create a UITable in the specified parent with column and optionally row names.
            table = uitable(parent);

            if nargin > 2 && ~isempty(columnNames)
                table.ColumnName = columnNames;
            end

            % Set RowName if rowNames is provided and not empty
            if nargin > 3 && ~isempty(rowNames)
                table.RowName = rowNames;
            end

            % Set layout properties
            table.Layout.Row = 1;
            table.Layout.Column = 1;
        end

        function imageData = extractImageData(~, data)
            % Check if the input data is empty
            if isempty(data)
                imageData = {}; % Return an empty cell array if data is empty
            else
                % Extract the last part of each file path
                parts = regexp(data{:, 1}, filesep, 'split');
                imageData = cellfun(@(x) x{end}, parts, 'UniformOutput', false);
            end
        end

        function applyCellStyling(~, table, data)
            % Apply styles based on Wilcoxon test results
            for x = 1:size(data, 1)
                for j = 1:size(data, 2)
                    value = cell2mat(data(x, j));
                    if value == 1
                        style = uistyle('BackgroundColor', [0 1 0]); % Green
                    elseif value == -1
                        style = uistyle('BackgroundColor', [1 0 0]); % Red
                    elseif value == 0
                        style = uistyle('BackgroundColor', [1 1 0]); % Yellow
                    else
                        % For any other value, use a default style
                        style = uistyle('BackgroundColor', [1 1 1]); % White
                    end
                    addStyle(table, style, 'cell', [x j]);
                end
            end
        end

        function customizeBoxplotAppearance(~, h)
            % Customize the appearance of a boxplot
            set(h(5, :), 'Color', 'r'); % Change the box color to red
            set(h(6, :), 'Color', 'g', 'LineWidth', 2); % Change median line to green
            set(h(1, :), 'LineStyle', '--'); % Change upper whisker style
            set(h(2, :), 'LineStyle', '--'); % Change lower whisker style
            set(h(7, :), 'Marker', '*', 'MarkerEdgeColor', 'b'); % Customize outlier markers
        end

        function saveAllResult(app, data)
            % Specify the directory for saving results
            resultsDir = 'Results';

            % Check if the results directory exists, if not, create it
            if ~exist(resultsDir, 'dir')
                mkdir(resultsDir);
            end

            % Create a timestamped filename for the Excel file
            timestamp = datestr(now, 'yyyy-mm-dd-HH-MM-SS');
            filename = fullfile(resultsDir, sprintf('result_%s.xlsx', timestamp));

            % Check if the first element in app.tablesTabsCorr contains any data
            if isempty(app.tablesTabsCorr{1}.Data)
                % If the data is empty, return from the function early
                % This prevents further operations when there is no data to save
                return;
            end

            % Loop through app.tablesTabsCorr to save each table to a separate sheet
            for i = 1:numel(app.tablesTabsCorr)
                % Get the data from the current table
                dataToSave = app.tablesTabsCorr{i}.Data;

                % Get the title for the sheet name
                sheetName = strcat('Corr_', app.tabsCorr{i}.Title);

                % Check the type of data and use the appropriate function
                if iscell(dataToSave)
                    writecell(dataToSave, filename, 'Sheet', sheetName);
                elseif isnumeric(dataToSave) || islogical(dataToSave)
                    writematrix(dataToSave, filename, 'Sheet', sheetName);
                end
            end

            % Loop through app.tablesTabsImScore to save each table to a separate sheet
            for i = 1:numel(app.tablesTabsImScore)
                % Get the data from the current table
                dataToSave = app.tablesTabsImScore{i}.Data;

                % Get the title for the sheet name
                sheetName = strcat('ImScore_', app.tabsImScore{i}.Title);

                % Check the type of data and use the appropriate function
                if iscell(dataToSave)
                    writecell(dataToSave, filename, 'Sheet', sheetName);
                elseif isnumeric(dataToSave) || islogical(dataToSave)
                    writematrix(dataToSave, filename, 'Sheet', sheetName);
                end
            end

            % Loop through app.tablesTabsWilcoxon to save each table to a separate sheet
            for i = 1:numel(app.tablesTabsWilcoxon)
                % Get the data from the current table
                dataToSave = app.tablesTabsWilcoxon{i}.Data;

                % Get the title for the sheet name
                sheetName = strcat('Wilcoxon_', app.tabsWilcoxon{i}.Title);

                % Check the type of data and use the appropriate function
                if iscell(dataToSave)
                    writecell(dataToSave, filename, 'Sheet', sheetName);
                elseif isnumeric(dataToSave) || islogical(dataToSave)
                    writematrix(dataToSave, filename, 'Sheet', sheetName);
                end
            end

            % Loop through app.tablesComplexityTab to save each table to a separate sheet
            for i = 1:numel(app.tablesComplexityTab)
                % Get the data from the current table
                dataToSave = app.tablesComplexityTab{i}.Data;

                % Define the sheet name based on the index
                switch i
                    case 1
                        % Get the title for the sheet name
                        sheetName = 'Training_time';
                    case 2
                        % Get the title for the sheet name
                        sheetName = 'Testing_time';
                end

                % Check the type of data and convert it to a table if necessary
                if iscell(dataToSave)
                    % Convert cell array to table to allow adding row/column names
                    dataToSave = cell2table(dataToSave);

                    % Check if VariableNames or RowNames exist in the Properties
                    if isprop(app.tablesComplexityTab{i}, 'ColumnName') && ~isempty(app.tablesComplexityTab{i}.ColumnName)
                        dataToSave.Properties.VariableNames = app.tablesComplexityTab{i}.ColumnName;
                    end
                    if isprop(app.tablesComplexityTab{i}, 'RowName') && ~isempty(app.tablesComplexityTab{i}.RowName)
                        dataToSave.Properties.RowNames = app.tablesComplexityTab{i}.RowName;
                    end

                    % Write the table with column and row names
                    writetable(dataToSave, filename, 'Sheet', sheetName, 'WriteRowNames', true);
                elseif ((isnumeric(dataToSave) || islogical(dataToSave)) && ~isempty(dataToSave))
                    % Convert numeric data to a table to enable row/column names
                    dataToSave = array2table(dataToSave);

                    % Check if VariableNames or RowNames exist in the Properties
                    if isprop(app.tablesComplexityTab{i}, 'ColumnName') && ~isempty(app.tablesComplexityTab{i}.ColumnName)
                        dataToSave.Properties.VariableNames = app.tablesComplexityTab{i}.ColumnName;
                    end
                    if isprop(app.tablesComplexityTab{i}, 'RowName') && ~isempty(app.tablesComplexityTab{i}.RowName)
                        dataToSave.Properties.RowNames = app.tablesComplexityTab{i}.RowName;
                    end

                    % Write the table with column and row names
                    writetable(dataToSave, filename, 'Sheet', sheetName, 'WriteRowNames', true);
                end
            end

            if size(app.tablesTabsCorr{1}.Data,1) > 1 && size(app.tmpOutScore{1}, 1) > 1
                for i = 1:numel(app.tabsUIAxes)
                    % Specify the filename and path
                    filename = fullfile('results', [app.tabsWilcoxon{i}.Title,'_plot_',timestamp,'.png']);
                    pause(0.5)
                    % Save the contents of the UIAxes to a PNG file
                    exportgraphics(app.tabsUIAxes{i}, filename, 'Resolution', 300);
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            % Set the main application object and hide the main UI figure
            app.MainApp = main;  % Store reference to the main application
            app.UIFigure.Visible = 'off';
            i = 1;  % Initialize index for creating tabs

            % Create a TabGroup for displaying correlation results
            app.TabGroupCorr = uitabgroup(app.GridLayout);  % Initialize a tab group within the specified grid layout
            app.TabGroupCorr.Layout.Row = [1 17];  % Span rows 1 to 17
            app.TabGroupCorr.Layout.Column = [1 8];  % Span columns 1 to 8

            % Create the first Tab for displaying median values across all splits
            app.tabsCorr{i} = uitab(app.TabGroupCorr, "Title", 'Median for all Splits');  % Add a tab titled 'Median for all Splits'

            % Create a GridLayout for the tab to organize UI elements
            app.gridCorr{i} = uigridlayout(app.tabsCorr{i});  % Initialize a grid layout within the tab
            app.gridCorr{i}.ColumnWidth = {'1x'};  % Set column width to 1x (flexible size)
            app.gridCorr{i}.RowHeight = {'1x'};  % Set row height to 1x (flexible size)
            app.gridCorr{i}.Padding = [0 0 0 0];  % Set padding around the grid layout to zero

            % Create a UITable to display correlation method results
            app.tablesTabsCorr{i} = uitable(app.gridCorr{i});  % Initialize a table within the grid layout
            app.tablesTabsCorr{i}.ColumnName = {'Method', 'Spearman', 'Kendall', 'Pearson'};  % Define column names for correlation metrics
            app.tablesTabsCorr{i}.Layout.Row = 1;  % Set the table to occupy row 1 of the grid layout
            app.tablesTabsCorr{i}.Layout.Column = 1;  % Set the table to occupy column 1 of the grid layout

            % Create a TabGroup for holding boxplot tabs
            app.TabBoxplotsTab = uitabgroup(app.GridLayout3);  % Initialize a new tab group for boxplots in the specified grid layout
            app.TabBoxplotsTab.Layout.Row = [1 17];  % Span rows 1 to 17 for boxplot tabs
            app.TabBoxplotsTab.Layout.Column = [1 8];  % Span columns 1 to 8 for boxplot tabs

            % Call a function to create all result tabs and display them
            createAllResultTab(app, app.MainApp);  % Create all result tabs and associate them with the main application

            pause(0.1)
            if app.MainApp.flagSaver == 1 %save all result
                saveAllResult(app, app.MainApp);
            end
            app.MainApp.UIFigure.Visible = 'off';  % Hide the main UI figure during setup
            app.UIFigure.Visible = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.OpenButton_Corr.Enable = 'on';
                app.MainApp.OpenButton_ImgScore_2.Enable ='on';
                app.MainApp.TrainandtestallButton.Enable = 'on';
                app.MainApp.SelectMethodOptionButton_2.Enable ="on";
                app.MainApp.SelectMethodOptionButton.Enable ='on';
                app.MainApp.UIFigure.Visible = 'on';
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CorrelationsTab = uitab(app.TabGroup);
            app.CorrelationsTab.Title = 'Correlations';
            app.GridLayout = uigridlayout(app.CorrelationsTab);
            app.ImageScoresTab = uitab(app.TabGroup);
            app.ImageScoresTab.Title = 'Image Scores';
            app.GridLayout5 = uigridlayout(app.ImageScoresTab);
            app.WilcoxonTab = uitab(app.TabGroup);
            app.WilcoxonTab.Title = 'Wilcoxon';
            app.GridLayout2 = uigridlayout(app.WilcoxonTab);
            app.BoxplotsTab = uitab(app.TabGroup);
            app.BoxplotsTab.Title = 'Boxplots';
            app.GridLayout3 = uigridlayout(app.BoxplotsTab);
            app.TimeTab = uitab(app.TabGroup);
            app.TimeTab.Title = 'Time';
            app.GridLayout4 = uigridlayout(app.TimeTab);
            app.MeantrainingtimeforasplitLabel = uilabel(app.GridLayout4);
            app.MeantrainingtimeforasplitLabel.Text = 'Mean training time for a split';
            app.MeantesttimeforanimageLabel = uilabel(app.GridLayout4);
            app.MeantesttimeforanimageLabel.Text = 'Mean test time for an image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = results(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end